classdef Chapter_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Ex1Tab                     matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        Sequences1                 matlab.ui.container.ButtonGroup
        acb1                       matlab.ui.control.RadioButton
        abc1                       matlab.ui.control.RadioButton
        example1                   matlab.ui.control.Label
        Vc1                        matlab.ui.control.EditField
        TheapparentpowerLabel_3    matlab.ui.control.Label
        Vb1                        matlab.ui.control.EditField
        TheapparentpowerLabel_2    matlab.ui.control.Label
        Va1                        matlab.ui.control.EditField
        TheapparentpowerLabel      matlab.ui.control.Label
        Van1                       matlab.ui.control.EditField
        VoltageVrmsLabel           matlab.ui.control.Label
        ex1_clearButton            matlab.ui.control.Button
        ex1_calculateButton        matlab.ui.control.Button
        Ex2Tab                     matlab.ui.container.Tab
        example2                   matlab.ui.control.Label
        Vann2                      matlab.ui.control.Label
        Ic2                        matlab.ui.control.EditField
        IaLabel_3                  matlab.ui.control.Label
        Ib2                        matlab.ui.control.EditField
        IaLabel_2                  matlab.ui.control.Label
        Ia2                        matlab.ui.control.EditField
        IaLabel                    matlab.ui.control.Label
        ex2_clearButton            matlab.ui.control.Button
        ex2_calculateButton        matlab.ui.control.Button
        Z2b                        matlab.ui.control.EditField
        Z2EditFieldLabel           matlab.ui.control.Label
        Z2a                        matlab.ui.control.EditField
        Z1EditFieldLabel           matlab.ui.control.Label
        Van2                       matlab.ui.control.EditField
        VanEditFieldLabel          matlab.ui.control.Label
        Sequences2                 matlab.ui.container.ButtonGroup
        acb2                       matlab.ui.control.RadioButton
        abc2                       matlab.ui.control.RadioButton
        Z2b1                       matlab.ui.control.Label
        Z2b2                       matlab.ui.control.Label
        Z2a1                       matlab.ui.control.Label
        Z2a2                       matlab.ui.control.Label
        Vbn2                       matlab.ui.control.Label
        Vcn2                       matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        DONTDELETELabel_2          matlab.ui.control.Label
        DONTDELETELabel            matlab.ui.control.Label
        Ex3Tab                     matlab.ui.container.Tab
        example3                   matlab.ui.control.Label
        Ic3                        matlab.ui.control.EditField
        IaLabel_9                  matlab.ui.control.Label
        Ib3                        matlab.ui.control.EditField
        IaLabel_8                  matlab.ui.control.Label
        Ia3                        matlab.ui.control.EditField
        IaLabel_7                  matlab.ui.control.Label
        Iac3                       matlab.ui.control.EditField
        IaLabel_6                  matlab.ui.control.Label
        Ibc3                       matlab.ui.control.EditField
        IaLabel_5                  matlab.ui.control.Label
        Iab3                       matlab.ui.control.EditField
        IaLabel_4                  matlab.ui.control.Label
        ex3_clearButton            matlab.ui.control.Button
        ex3_calculateButton        matlab.ui.control.Button
        Sequences3                 matlab.ui.container.ButtonGroup
        acb3                       matlab.ui.control.RadioButton
        abc3                       matlab.ui.control.RadioButton
        Vann3                      matlab.ui.control.Label
        Z3a                        matlab.ui.control.Label
        Z3b                        matlab.ui.control.Label
        Vcn3                       matlab.ui.control.Label
        Vbn3                       matlab.ui.control.Label
        Z3                         matlab.ui.control.EditField
        ZEditFieldLabel            matlab.ui.control.Label
        Van3                       matlab.ui.control.EditField
        VanEditField_2Label        matlab.ui.control.Label
        Ima3                       matlab.ui.control.Image
        Ex4Tab                     matlab.ui.container.Tab
        example4                   matlab.ui.control.Label
        Vba4                       matlab.ui.control.Label
        Z4b                        matlab.ui.control.Label
        Z4a                        matlab.ui.control.Label
        Vbc4                       matlab.ui.control.Label
        Vca4                       matlab.ui.control.Label
        Ic4                        matlab.ui.control.EditField
        IaLabel_15                 matlab.ui.control.Label
        Ib4                        matlab.ui.control.EditField
        IaLabel_14                 matlab.ui.control.Label
        Ia4                        matlab.ui.control.EditField
        IaLabel_13                 matlab.ui.control.Label
        Iac4                       matlab.ui.control.EditField
        IaLabel_12                 matlab.ui.control.Label
        Ibc4                       matlab.ui.control.EditField
        IaLabel_11                 matlab.ui.control.Label
        Iab4                       matlab.ui.control.EditField
        IaLabel_10                 matlab.ui.control.Label
        ex4_clearButton            matlab.ui.control.Button
        ex4_calculateButton        matlab.ui.control.Button
        Sequences4                 matlab.ui.container.ButtonGroup
        acb4                       matlab.ui.control.RadioButton
        abc4                       matlab.ui.control.RadioButton
        Z4                         matlab.ui.control.EditField
        ZEditFieldLabel_2          matlab.ui.control.Label
        Vab4                       matlab.ui.control.EditField
        VanEditField_2Label_2      matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        Ex5Tab                     matlab.ui.container.Tab
        example5                   matlab.ui.control.Label
        Ic5                        matlab.ui.control.EditField
        IaLabel_21                 matlab.ui.control.Label
        Ib5                        matlab.ui.control.EditField
        IaLabel_20                 matlab.ui.control.Label
        Ia5                        matlab.ui.control.EditField
        IaLabel_19                 matlab.ui.control.Label
        Z5                         matlab.ui.control.EditField
        ZEditFieldLabel_3          matlab.ui.control.Label
        Vab5                       matlab.ui.control.EditField
        VanEditField_2Label_3      matlab.ui.control.Label
        Vba5                       matlab.ui.control.Label
        Z5b                        matlab.ui.control.Label
        Z5a                        matlab.ui.control.Label
        Vbc5                       matlab.ui.control.Label
        Vca5                       matlab.ui.control.Label
        ex5_clearButton            matlab.ui.control.Button
        ex5_calculateButton        matlab.ui.control.Button
        Sequences5                 matlab.ui.container.ButtonGroup
        acb5                       matlab.ui.control.RadioButton
        abc5                       matlab.ui.control.RadioButton
        Image5                     matlab.ui.control.Image
        Ex6Tab                     matlab.ui.container.Tab
        example6                   matlab.ui.control.Label
        St6                        matlab.ui.control.EditField
        IaLabel_25                 matlab.ui.control.Label
        Sli6                       matlab.ui.control.EditField
        IaLabel_24                 matlab.ui.control.Label
        Slo6                       matlab.ui.control.EditField
        IaLabel_23                 matlab.ui.control.Label
        Ss6                        matlab.ui.control.EditField
        IaLabel_22                 matlab.ui.control.Label
        Z6b                        matlab.ui.control.EditField
        Z2EditFieldLabel_2         matlab.ui.control.Label
        Z6a                        matlab.ui.control.EditField
        Z1EditFieldLabel_2         matlab.ui.control.Label
        Van6                       matlab.ui.control.EditField
        VanEditFieldLabel_2        matlab.ui.control.Label
        Vann6                      matlab.ui.control.Label
        ex6_clearButton            matlab.ui.control.Button
        ex6_calculateButton        matlab.ui.control.Button
        Sequences6                 matlab.ui.container.ButtonGroup
        acb6                       matlab.ui.control.RadioButton
        abc6                       matlab.ui.control.RadioButton
        Z6b2                       matlab.ui.control.Label
        Z6b1                       matlab.ui.control.Label
        Z6a2                       matlab.ui.control.Label
        Z6a1                       matlab.ui.control.Label
        Vbn6                       matlab.ui.control.Label
        Vcn6                       matlab.ui.control.Label
        Image6                     matlab.ui.control.Image
        Ex7Tab                     matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        example7                   matlab.ui.control.Label
        pf7                        matlab.ui.control.EditField
        PowerfactorEditFieldLabel  matlab.ui.control.Label
        i7                         matlab.ui.control.EditField
        ThelinecurrentLabel        matlab.ui.control.Label
        v7                         matlab.ui.control.EditField
        ThelinevoltageLabel        matlab.ui.control.Label
        p7                         matlab.ui.control.EditField
        ThepowerLabel              matlab.ui.control.Label
        ex7_clearButton            matlab.ui.control.Button
        ex7_calculateButton        matlab.ui.control.Button
        Ex8Tab                     matlab.ui.container.Tab
        DAHASONRAINCELELabel       matlab.ui.control.Label
        pf8a_2                     matlab.ui.control.EditField
        VrmsLabel_10               matlab.ui.control.Label
        laglead8b_2                matlab.ui.container.ButtonGroup
        lead8b_3                   matlab.ui.control.RadioButton
        lag8b_2                    matlab.ui.control.RadioButton
        example8                   matlab.ui.control.Label
        ex8_clearButton            matlab.ui.control.Button
        ex8_calculateButton        matlab.ui.control.Button
        pf8b                       matlab.ui.control.EditField
        VrmsLabel_5                matlab.ui.control.Label
        q8b                        matlab.ui.control.EditField
        VrmsLabel_4                matlab.ui.control.Label
        pf8a                       matlab.ui.control.EditField
        VrmsLabel_3                matlab.ui.control.Label
        q8a                        matlab.ui.control.EditField
        VrmsLabel_2                matlab.ui.control.Label
        Sequences8                 matlab.ui.container.ButtonGroup
        acb8                       matlab.ui.control.RadioButton
        abc8                       matlab.ui.control.RadioButton
        laglead8b                  matlab.ui.container.ButtonGroup
        lead8b                     matlab.ui.control.RadioButton
        lag8b                      matlab.ui.control.RadioButton
        Forload2Label              matlab.ui.control.Label
        laglead8a                  matlab.ui.container.ButtonGroup
        lead8a                     matlab.ui.control.RadioButton
        lag8a                      matlab.ui.control.RadioButton
        Forload1Label              matlab.ui.control.Label
        f8                         matlab.ui.control.EditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        v8                         matlab.ui.control.EditField
        VrmsLabel                  matlab.ui.control.Label
        Image8                     matlab.ui.control.Image
        Ex9Tab                     matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        example9                   matlab.ui.control.Label
        Sequences9                 matlab.ui.container.ButtonGroup
        acb9                       matlab.ui.control.RadioButton
        abc9                       matlab.ui.control.RadioButton
        In9                        matlab.ui.control.EditField
        IaLabel_29                 matlab.ui.control.Label
        Ic9                        matlab.ui.control.EditField
        IaLabel_28                 matlab.ui.control.Label
        Ib9                        matlab.ui.control.EditField
        IaLabel_27                 matlab.ui.control.Label
        Ia9                        matlab.ui.control.EditField
        IaLabel_26                 matlab.ui.control.Label
        z9c                        matlab.ui.control.EditField
        VrmsLabel_9                matlab.ui.control.Label
        z9b                        matlab.ui.control.EditField
        VrmsLabel_8                matlab.ui.control.Label
        z9a                        matlab.ui.control.EditField
        VrmsLabel_7                matlab.ui.control.Label
        v9                         matlab.ui.control.EditField
        VrmsLabel_6                matlab.ui.control.Label
        ex9_clearButton            matlab.ui.control.Button
        ex9_calculateButton        matlab.ui.control.Button
        Image9                     matlab.ui.control.Image
        Ex10Tab                    matlab.ui.container.Tab
        example10                  matlab.ui.control.Label
        Ssource10                  matlab.ui.control.EditField
        IaLabel_34                 matlab.ui.control.Label
        Sload10                    matlab.ui.control.EditField
        IaLabel_33                 matlab.ui.control.Label
        Ic10                       matlab.ui.control.EditField
        IaLabel_32                 matlab.ui.control.Label
        Ib10                       matlab.ui.control.EditField
        IaLabel_31                 matlab.ui.control.Label
        Ia10                       matlab.ui.control.EditField
        IaLabel_30                 matlab.ui.control.Label
        C10                        matlab.ui.control.EditField
        RLabel_3                   matlab.ui.control.Label
        R10                        matlab.ui.control.EditField
        RLabel_2                   matlab.ui.control.Label
        L10                        matlab.ui.control.EditField
        RLabel                     matlab.ui.control.Label
        Van10                      matlab.ui.control.EditField
        VanLabel                   matlab.ui.control.Label
        Vann10                     matlab.ui.control.Label
        ex10_clearButton           matlab.ui.control.Button
        ex10_calculateButton       matlab.ui.control.Button
        Sequences10                matlab.ui.container.ButtonGroup
        acb10                      matlab.ui.control.RadioButton
        abc10                      matlab.ui.control.RadioButton
        Vbn10                      matlab.ui.control.Label
        Vcn10                      matlab.ui.control.Label
        Image10                    matlab.ui.control.Image
    end


    methods (Access = private)
        % function that converts polar to rectangular form
        function rectForm = polarToRect(app, event)
            value = event.Source.Value;
            splitOperator = split(value, ',');
            if length(splitOperator) == 2
                app.DONTDELETELabel.Text = 'rect func';
                a = value;
                a = split(a, ',');
                mag = eval(a{1,1});
                phaseAngle = eval(a{2,1});
                real = mag * cos(deg2rad(phaseAngle));
                real = round(real, 2);
                imag = mag * sin(deg2rad(phaseAngle));
                imag = round(imag, 2);
                rectForm = string(complex(real, imag));
            else
                rectForm = value;
            end
        end
        
        % function that converts rectangular to polar form
        function polarForm = rectToPolar(app, value)
            app.DONTDELETELabel_2.Text = 'polar func';
            a = string(value);
            mag = abs(eval(a));
            ang = rad2deg(angle(eval(a)));
            polarForm = '(' + string(round(mag, 2)) + ',' + string(round(ang, 2)) + ')'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: C10, L10, R10, Vab4, Vab5, Vab5, Van1, Van1, 
        % Van10, Van2, Van3, Van3, Van6, Z2a, Z2b, Z3, Z3, Z4, Z5, Z5, 
        % Z6a, Z6b, i7, p7, v7, v9, z9a, z9b, z9c
        function polarForm(app, event)
            event.Source.Value = polarToRect(app, event);
        end

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            % input values
            Van = eval(app.Van1.Value);
           
            % ---- CALCULATIONS ----
            % abc or acb sequence
            if app.abc1.Value == 1
                ang_Vbn = rad2deg(angle(Van)) - 120;
                ang_Vcn = rad2deg(angle(Van)) + 120;

            elseif app.acb1.Value == 1
                ang_Vbn = rad2deg(angle(Van)) + 120;
                ang_Vcn = rad2deg(angle(Van)) - 120;
            end
            
            mag_Vbn = abs(Van);
            mag_Vcn = abs(Van);
            Vbn = mag_Vbn * complex(cos(deg2rad(ang_Vbn)), sin(deg2rad(ang_Vbn)));
            Vcn = mag_Vcn * complex(cos(deg2rad(ang_Vcn)), sin(deg2rad(ang_Vcn)));

            Van = round(Van, 2);
            Vbn = round(Vbn, 2);
            Vcn = round(Vcn, 2);
            
            % print operations
            app.Va1.Value = string(Van) + ' or ' + rectToPolar(app, Van) + ' V';
            app.Vb1.Value = string(Vbn) + ' or ' + rectToPolar(app, Vbn) + ' V';
            app.Vc1.Value = string(Vcn) + ' or ' + rectToPolar(app, Vcn) + ' V';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.Van1.Value = '';
            app.Va1.Value = '';
            app.Vb1.Value = '';
            app.Vc1.Value = '';
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % input values
            Van = eval(app.Van2.Value);
            Z1 = eval(app.Z2a.Value);
            Z2 = eval(app.Z2b.Value);
            
            % ---- CALCULATIONS ----
            Zy = Z1 + Z2;
            Ia = Van / Zy;
            % abc or acb sequence
            if app.abc2.Value == 1
                ang_Ib = rad2deg(angle(Ia)) - 120;
                ang_Ic = rad2deg(angle(Ia)) + 120;

                ang_Vbn = rad2deg(angle(Van)) - 120;
                ang_Vcn = rad2deg(angle(Van)) + 120;

            elseif app.acb2.Value == 1
                ang_Ib = rad2deg(angle(Ia)) + 120;
                ang_Ic = rad2deg(angle(Ia)) - 120;

                ang_Vbn = rad2deg(angle(Van)) + 120;
                ang_Vcn = rad2deg(angle(Van)) - 120;
            end
            
            mag_Ib = abs(Ia);
            mag_Ic = abs(Ia);
            Ib = mag_Ib * complex(cos(deg2rad(ang_Ib)), sin(deg2rad(ang_Ib)));
            Ic = mag_Ic * complex(cos(deg2rad(ang_Ic)), sin(deg2rad(ang_Ic)));

            mag_Vbn = abs(Van);
            mag_Vcn = abs(Van);
            Vbn = mag_Vbn * complex(cos(deg2rad(ang_Vbn)), sin(deg2rad(ang_Vbn)));
            Vcn = mag_Vcn * complex(cos(deg2rad(ang_Vcn)), sin(deg2rad(ang_Vcn)));

            Ia = round(Ia, 2);
            Ib = round(Ib, 2);
            Ic = round(Ic, 2);
            
            Van = round(Van, 2);
            Vbn = round(Vbn, 2);
            Vcn = round(Vcn, 2);
            
            % print operations
            app.Ia2.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib2.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic2.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';
            app.Vann2.Text = rectToPolar(app, Van) + ' V';
            app.Vbn2.Text = rectToPolar(app, Vbn) + ' V';
            app.Vcn2.Text = rectToPolar(app, Vcn) + ' V';
        end

        % Button pushed function: ex2_clearButton
        function ex2_clearButtonPushed(app, event)
            app.Van2.Value = '';
            app.Vann2.Text = 'Van';
            app.Vbn2.Text = 'Vbn';
            app.Vcn2.Text = 'Vc';
            app.Z2a.Value = '';
            app.Z2b.Value = '';
            app.Ia2.Value = '';
            app.Ib2.Value = '';
            app.Ic2.Value = '';            
        end

        % Button pushed function: ex3_calculateButton
        function ex3_calculateButtonPushed(app, event)
            % input values
            Van = eval(app.Van3.Value);
            Zdelta = eval(app.Z3.Value);
            
            % ---- CALCULATIONS ----
            mag_Van = abs(Van);
            ang_Van = rad2deg(angle(Van)) + 30;
            Vab = sqrt(3) * mag_Van ...
                * complex(cos(deg2rad(ang_Van)), sin(deg2rad(ang_Van)));
            
            Iab = Vab / Zdelta;
            Ia = Van / (Zdelta / 3);

            % abc or acb sequence
            if app.abc3.Value == 1
                ang_Ibc = rad2deg(angle(Iab)) - 120;
                ang_Iac = rad2deg(angle(Iab)) + 120;

                ang_Ib = rad2deg(angle(Ia)) - 120;
                ang_Ic = rad2deg(angle(Ia)) + 120;

                ang_Vbn = rad2deg(angle(Van)) - 120;
                ang_Vcn = rad2deg(angle(Van)) + 120;

            elseif app.acb3.Value == 1
                ang_Ibc = rad2deg(angle(Iab)) + 120;
                ang_Iac = rad2deg(angle(Iab)) - 120;

                ang_Ib = rad2deg(angle(Ia)) + 120;
                ang_Ic = rad2deg(angle(Ia)) - 120;

                ang_Vbn = rad2deg(angle(Van)) + 120;
                ang_Vcn = rad2deg(angle(Van)) - 120;
            end
            
            mag_Ibc = abs(Iab);
            mag_Iac = abs(Iab);
            Ibc = mag_Ibc * complex(cos(deg2rad(ang_Ibc)), sin(deg2rad(ang_Ibc)));
            Iac = mag_Iac * complex(cos(deg2rad(ang_Iac)), sin(deg2rad(ang_Iac)));

            mag_Ib = abs(Ia);
            mag_Ic = abs(Ia);
            Ib = mag_Ib * complex(cos(deg2rad(ang_Ib)), sin(deg2rad(ang_Ib)));
            Ic = mag_Ic * complex(cos(deg2rad(ang_Ic)), sin(deg2rad(ang_Ic)));

            mag_Vbn = abs(Van);
            mag_Vcn = abs(Van);
            Vbn = mag_Vbn * complex(cos(deg2rad(ang_Vbn)), sin(deg2rad(ang_Vbn)));
            Vcn = mag_Vcn * complex(cos(deg2rad(ang_Vcn)), sin(deg2rad(ang_Vcn)));
            
            Iab = round(Iab, 2);
            Ibc = round(Ibc, 2);
            Iac = round(Iac, 2);

            Ia = round(Ia, 2);
            Ib = round(Ib, 2);
            Ic = round(Ic, 2);
            
            Van = round(Van, 2);
            Vbn = round(Vbn, 2);
            Vcn = round(Vcn, 2);
            
            % print operations
            app.Iab3.Value = string(Iab) + ' or ' + rectToPolar(app, Iab) + ' A';
            app.Ibc3.Value = string(Ibc) + ' or ' + rectToPolar(app, Ibc) + ' A';
            app.Iac3.Value = string(Iac) + ' or ' + rectToPolar(app, Iac) + ' A';

            app.Ia3.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib3.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic3.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';

            app.Vann3.Text = rectToPolar(app, Van) + ' V';
            app.Vbn3.Text = rectToPolar(app, Vbn) + ' V';
            app.Vcn3.Text = rectToPolar(app, Vcn) + ' V';

        end

        % Button pushed function: ex3_clearButton
        function ex3_clearButtonPushed(app, event)
            app.Van3.Value = '';
            app.Z3.Value = '';
            app.Vann2.Text = 'Van';
            app.Vbn3.Text = 'Vbn';
            app.Vcn3.Text = 'Vc';
            app.Iab3.Value = '';
            app.Ibc3.Value = '';
            app.Iac3.Value = '';
            app.Ia3.Value = '';
            app.Ib3.Value = '';
            app.Ic3.Value = '';
        end

        % Button pushed function: ex4_calculateButton
        function ex4_calculateButtonPushed(app, event)
            % input values
            Vab = eval(app.Vab4.Value);
            Zdelta = eval(app.Z4.Value);
            
            % ---- CALCULATIONS ----
            Iab = Vab / Zdelta;
            mag_Ia = abs(Iab);
            ang_Ia = rad2deg(angle(Iab)) - 30;
            Ia = sqrt(3) * mag_Ia ...
                * complex(cos(deg2rad(ang_Ia)), sin(deg2rad(ang_Ia)));
            
            % abc or acb sequence
            if app.abc4.Value == 1
                ang_Ibc = rad2deg(angle(Iab)) - 120;
                ang_Iac = rad2deg(angle(Iab)) + 120;

                ang_Ib = rad2deg(angle(Ia)) - 120;
                ang_Ic = rad2deg(angle(Ia)) + 120;

                ang_Vbc = rad2deg(angle(Vab)) - 120;
                ang_Vca = rad2deg(angle(Vab)) + 120;

            elseif app.acb4.Value == 1
                ang_Ibc = rad2deg(angle(Iab)) + 120;
                ang_Iac = rad2deg(angle(Iab)) - 120;

                ang_Ib = rad2deg(angle(Ia)) + 120;
                ang_Ic = rad2deg(angle(Ia)) - 120;

                ang_Vbc = rad2deg(angle(Vab)) + 120;
                ang_Vca = rad2deg(angle(Vab)) - 120;
            end
            
            mag_Ibc = abs(Iab);
            mag_Iac = abs(Iab);
            Ibc = mag_Ibc * complex(cos(deg2rad(ang_Ibc)), sin(deg2rad(ang_Ibc)));
            Iac = mag_Iac * complex(cos(deg2rad(ang_Iac)), sin(deg2rad(ang_Iac)));

            mag_Ib = abs(Ia);
            mag_Ic = abs(Ia);
            Ib = mag_Ib * complex(cos(deg2rad(ang_Ib)), sin(deg2rad(ang_Ib)));
            Ic = mag_Ic * complex(cos(deg2rad(ang_Ic)), sin(deg2rad(ang_Ic)));

            mag_Vbc = abs(Vab);
            mag_Vca = abs(Vab);
            Vbc = mag_Vbc * complex(cos(deg2rad(ang_Vbc)), sin(deg2rad(ang_Vbc)));
            Vca = mag_Vca * complex(cos(deg2rad(ang_Vca)), sin(deg2rad(ang_Vca)));

            Iab = round(Iab, 2);
            Ibc = round(Ibc, 2);
            Iac = round(Iac, 2);

            Ia = round(Ia, 2);
            Ib = round(Ib, 2);
            Ic = round(Ic, 2);
            
            Vab = round(Vab, 2);
            Vbc = round(Vbc, 2);
            Vca = round(Vca, 2);
            
            % print operations
            app.Iab4.Value = string(Iab) + ' or ' + rectToPolar(app, Iab) + ' A';
            app.Ibc4.Value = string(Ibc) + ' or ' + rectToPolar(app, Ibc) + ' A';
            app.Iac4.Value = string(Iac) + ' or ' + rectToPolar(app, Iac) + ' A';

            app.Ia4.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib4.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic4.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';

            app.Vba4.Text = rectToPolar(app, Vab) + ' V';
            app.Vbc4.Text = rectToPolar(app, Vbc) + ' V';
            app.Vca4.Text = rectToPolar(app, Vca) + ' V';
        end

        % Button pushed function: ex4_clearButton
        function ex4_clearButtonPushed(app, event)
            app.Vab4.Value = '';
            app.Z4.Value = '';
            app.Vba4.Text = 'Vab';
            app.Vbc4.Text = 'Vbc';
            app.Vca4.Text = 'Vca';
            app.Iab4.Value = '';
            app.Ibc4.Value = '';
            app.Iac4.Value = '';
            app.Ia4.Value = '';
            app.Ib4.Value = '';
            app.Ic4.Value = '';
        end

        % Button pushed function: ex5_calculateButton
        function ex5_calculateButtonPushed(app, event)
            % input values
            Vab = eval(app.Vab5.Value);
            Zy = eval(app.Z5.Value);
            
            % ---- CALCULATIONS ----
            mag_Van = abs(Vab);
            ang_Van = rad2deg(angle(Vab)) - 30;
            Van = (mag_Van / sqrt(3))  ...
                * complex(cos(deg2rad(ang_Van)), sin(deg2rad(ang_Van)));
            
            Ia = Van / Zy;
            
            % abc or acb sequence
            if app.abc5.Value == 1
                ang_Ib = rad2deg(angle(Ia)) - 120;
                ang_Ic = rad2deg(angle(Ia)) + 120;

                ang_Vbc = rad2deg(angle(Vab)) - 120;
                ang_Vca = rad2deg(angle(Vab)) + 120;

            elseif app.acb5.Value == 1
                ang_Ib = rad2deg(angle(Ia)) + 120;
                ang_Ic = rad2deg(angle(Ia)) - 120;

                ang_Vbc = rad2deg(angle(Vab)) + 120;
                ang_Vca = rad2deg(angle(Vab)) - 120;
            end

            mag_Ib = abs(Ia);
            mag_Ic = abs(Ia);
            Ib = mag_Ib * complex(cos(deg2rad(ang_Ib)), sin(deg2rad(ang_Ib)));
            Ic = mag_Ic * complex(cos(deg2rad(ang_Ic)), sin(deg2rad(ang_Ic)));

            mag_Vbc = abs(Vab);
            mag_Vca = abs(Vab);
            Vbc = mag_Vbc * complex(cos(deg2rad(ang_Vbc)), sin(deg2rad(ang_Vbc)));
            Vca = mag_Vca * complex(cos(deg2rad(ang_Vca)), sin(deg2rad(ang_Vca)));

            Ia = round(Ia, 2);
            Ib = round(Ib, 2);
            Ic = round(Ic, 2);
            
            Vab = round(Vab, 2);
            Vbc = round(Vbc, 2);
            Vca = round(Vca, 2);
            
            % print operations
            app.Ia5.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib5.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic5.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';

            app.Vba5.Text = rectToPolar(app, Vab) + ' V';
            app.Vbc5.Text = rectToPolar(app, Vbc) + ' V';
            app.Vca5.Text = rectToPolar(app, Vca) + ' V';
        end

        % Button pushed function: ex5_clearButton
        function ex5_clearButtonPushed(app, event)
            app.Vab5.Value = '';
            app.Z5.Value = '';
            app.Vba5.Text = 'Vab';
            app.Vbc5.Text = 'Vbc';
            app.Vca5.Text = 'Vca';
            app.Ia5.Value = '';
            app.Ib5.Value = '';
            app.Ic5.Value = '';
        end

        % Button pushed function: ex6_calculateButton
        function ex6_calculateButtonPushed(app, event)
            % input values
            Van = eval(app.Van6.Value);
            Z1 = eval(app.Z6a.Value);
            Z2 = eval(app.Z6b.Value);
            
            % ---- CALCULATIONS ----
            Zy = Z1 + Z2;
            Ia = Van / Zy;
            
            % abc or acb sequence
            if app.abc6.Value == 1
                ang_Vbn = rad2deg(angle(Van)) - 120;
                ang_Vcn = rad2deg(angle(Van)) + 120;

            elseif app.acb6.Value == 1
                ang_Vbn = rad2deg(angle(Van)) + 120;
                ang_Vcn = rad2deg(angle(Van)) - 120;
            end

            mag_Vbn = abs(Van);
            mag_Vcn = abs(Van);
            Vbn = mag_Vbn * complex(cos(deg2rad(ang_Vbn)), sin(deg2rad(ang_Vbn)));
            Vcn = mag_Vcn * complex(cos(deg2rad(ang_Vcn)), sin(deg2rad(ang_Vcn)));

            Vp = Van;
            Ip = Ia;
            Zp = Z2;
            Zl = Z1;

            Ssource = -3 * Vp * conj(Ip);
            Sload = 3 * abs(Ip)^2 * Zp;
            Sline = 3 * abs(Ip)^2 * Zl;
            Stotal = Ssource + Sload + Sline;

            Ssource = round(Ssource, 2);
            Sload = round(Sload, 2);
            Sline = round(Sline, 2);
            Stotal = round(Stotal, 2);
            
            % print operations
            app.Ss6.Value = string(real(Ssource)) + ' W ' + string(imag(Ssource)) + ' VAR' ...
                + ' or ' + rectToPolar(app, Ssource) + ' VA';
            app.Slo6.Value = string(real(Sload)) + ' W ' + string(imag(Sload)) + ' VAR' ...
                + ' or ' + rectToPolar(app, Sload) + ' VA';
            app.Sli6.Value = string(real(Sline)) + ' W ' + string(imag(Sline)) + ' VAR' ...
                + ' or ' + rectToPolar(app, Sline) + ' VA';
            app.St6.Value = string(Stotal) + ' VA';

            app.Vann6.Text = rectToPolar(app, Van) + ' V';
            app.Vbn6.Text = rectToPolar(app, Vbn) + ' V';
            app.Vcn6.Text = rectToPolar(app, Vcn) + ' V';
        end

        % Button pushed function: ex6_clearButton
        function ex6_clearButtonPushed(app, event)
            app.Van6.Value = '';
            app.Vann6.Text = 'Van';
            app.Vbn6.Text = 'Vbn';
            app.Vcn6.Text = 'Vc';
            app.Z6a.Value = '';
            app.Z6b.Value = '';
            app.Ss6.Value = '';
            app.Slo6.Value = '';
            app.Sli6.Value = '';
            app.St6.Value = '';
        end

        % Button pushed function: ex7_calculateButton
        function ex7_calculateButtonPushed(app, event)
            % input values
            P = eval(app.p7.Value);
            VL = eval(app.v7.Value);
            IL = eval(app.i7.Value);

            % calculations
            S = sqrt(3) * VL * IL; % apparent power
            pf = P / S; % power factor
            
            % print operation
            app.pf7.Value = string(pf);
        end

        % Button pushed function: ex7_clearButton
        function ex7_clearButtonPushed(app, event)
            app.pf7.Value = '';
            app.v7.Value = '';
            app.i7.Value = '';
            app.pf7.Value = '';
        end

        % Button pushed function: ex8_calculateButton
        function ex8_calculateButtonPushed(app, event)
            
        end

        % Button pushed function: ex8_clearButton
        function ex8_clearButtonPushed(app, event)
            
        end

        % Button pushed function: ex9_calculateButton
        function ex9_calculateButtonPushed(app, event)
            % input values
            V = eval(app.v9.Value);
            Za = eval(app.z9a.Value);
            Zb = eval(app.z9b.Value);
            Zc = eval(app.z9c.Value);
            
            % ----- CALCULATIONS -----

            % abc or acb sequence
            if app.abc9.Value == 1
                ang_Vb = rad2deg(angle(V)) - 120;
                ang_Vc = rad2deg(angle(V)) + 120;

            elseif app.acb9.Value == 1
                ang_Vb = rad2deg(angle(V)) + 120;
                ang_Vc = rad2deg(angle(V)) - 120;
            end

            Vb = abs(V) * complex(cos(deg2rad(ang_Vb)), sin(deg2rad(ang_Vb))); % Vb
            Vc = abs(V) * complex(cos(deg2rad(ang_Vc)), sin(deg2rad(ang_Vc))); % Vc
            
            Ia = V / Za; % line current a
            Ib = Vb / Zb; % line current b
            Ic = Vc / Zc; % line current c
            In = -(Ia + Ib + Ic); % neutral line current

            Ia = round(Ia, 2);
            Ib = round(Ib, 2);
            Ic = round(Ic, 2);
            In = round(In, 2);
            
            % print operations
            app.Ia9.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib9.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic9.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';
            app.In9.Value = string(In) + ' or ' + rectToPolar(app, In) + ' A';        
        end

        % Button pushed function: ex9_clearButton
        function ex9_clearButtonPushed(app, event)
            app.v9.Value = '';
            app.z9a.Value = '';
            app.z9b.Value = '';
            app.z9c.Value = '';
            app.Ia9.Value = '';
            app.Ib9.Value = '';
            app.Ic9.Value = '';
            app.In9.Value = '';
        end

        % Button pushed function: ex10_calculateButton
        function ex10_calculateButtonPushed(app, event)
            % input values
            Van = eval(app.Van10.Value);
            ind = eval(app.L10.Value);
            res = eval(app.R10.Value);
            cap = eval(app.C10.Value);
            
            % ------ CALCULATIONS ------

            % abc or acb sequence
            if app.abc10.Value == 1
                ang_Vbn = rad2deg(angle(Van)) - 120;
                ang_Vcn = rad2deg(angle(Van)) + 120;

            elseif app.acb10.Value == 1
                ang_Vbn = rad2deg(angle(Van)) + 120;
                ang_Vcn = rad2deg(angle(Van)) - 120;
            end

            mag_Vbn = abs(Van);
            mag_Vcn = abs(Van);
            Vbn = mag_Vbn * complex(cos(deg2rad(ang_Vbn)), sin(deg2rad(ang_Vbn)));
            Vcn = mag_Vcn * complex(cos(deg2rad(ang_Vcn)), sin(deg2rad(ang_Vcn)));
            
            % mesh analysis operations
            ang_V1 = rad2deg(angle(Van)) + 30;
            V1 = sqrt(3) * abs(Van) * complex(cos(deg2rad(ang_V1)), sin(deg2rad(ang_V1)));
            ang_V2 = rad2deg(angle(Vbn)) + 30;
            V2 = sqrt(3) * abs(Van) * complex(cos(deg2rad(ang_V2)), sin(deg2rad(ang_V2)));
            
            A = [(res + ind), -res; -res, (res + cap)];
            V = [V1; V2];
            I = A^(-1) * V; % A.I = V matrix
            I1 = I(1,1);
            I2 = I(2,1);

            % line currents
            Ia = round(I1, 2);
            Ib = round((I2 - I1), 2);
            Ic = round(-I2, 2);
            
            
            % total complex power absorbed by the load
            S_A = abs(Ia)^2 * ind;
            S_B = abs(Ib)^2 * res;
            S_C = abs(Ic)^2 * cap;
            Sload = S_A + S_B + S_C;
            Sload = round(Sload, 1);
            
            % total complex power absorbed by the load
            Sa = -Van * conj(Ia);
            Sb = -Vbn * conj(Ib);
            Sc = -Vcn * conj(Ic);
            Ssource = Sa + Sb + Sc;
            Ssource = round(Ssource, 1);

            % Stotal = Sload + Ssource; % total complex power

            % print operations
            app.Vann10.Text = rectToPolar(app, Van) + ' V';
            app.Vbn10.Text = rectToPolar(app, Vbn) + ' V';
            app.Vcn10.Text = rectToPolar(app, Vcn) + ' V';
            app.Ia10.Value = string(Ia) + ' or ' + rectToPolar(app, Ia) + ' A';
            app.Ib10.Value = string(Ib) + ' or ' + rectToPolar(app, Ib) + ' A';
            app.Ic10.Value = string(Ic) + ' or ' + rectToPolar(app, Ic) + ' A';
            app.Sload10.Value = string(Sload) + ' or ' + rectToPolar(app, Sload) + ' VA';
            app.Ssource10.Value = string(Ssource) + ' or ' + rectToPolar(app, Ssource) + ' VA';
        end

        % Button pushed function: ex10_clearButton
        function ex10_clearButtonPushed(app, event)
            app.Van10.Value = '';
            app.Vann10.Text = 'Van(rms)';
            app.Vbn10.Text = 'Vbn(rms)';
            app.Vcn10.Text = 'Vcn(rms)';
            app.L10.Value = '';
            app.R10.Value = '';
            app.C10.Value = '';
            app.Ia10.Value = '';
            app.Ib10.Value = '';
            app.Ic10.Value = '';
            app.Sload10.Value = '';
            app.Ssource10.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex1Tab = uitab(app.TabGroup);
            app.Ex1Tab.Title = 'Ex-1';
            app.GridLayout = uigridlayout(app.Ex1Tab);
            app.ex1_calculateButton = uibutton(app.GridLayout, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.GridLayout, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.VoltageVrmsLabel = uilabel(app.GridLayout);
            app.VoltageVrmsLabel.Text = 'Van: ';
            app.Van1 = uieditfield(app.GridLayout, 'text');
            app.Van1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Van1.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Van1.Placeholder = 'V';
            app.TheapparentpowerLabel = uilabel(app.GridLayout);
            app.TheapparentpowerLabel.Text = 'Van:';
            app.Va1 = uieditfield(app.GridLayout, 'text');
            app.Va1.Editable = 'off';
            app.Va1.Placeholder = 'Van';
            app.TheapparentpowerLabel_2 = uilabel(app.GridLayout);
            app.TheapparentpowerLabel_2.Text = 'Vbn:';
            app.Vb1 = uieditfield(app.GridLayout, 'text');
            app.Vb1.Editable = 'off';
            app.Vb1.Placeholder = 'Vbn';
            app.TheapparentpowerLabel_3 = uilabel(app.GridLayout);
            app.TheapparentpowerLabel_3.Text = 'Vcn:';
            app.Vc1 = uieditfield(app.GridLayout, 'text');
            app.Vc1.Editable = 'off';
            app.Vc1.Placeholder = 'Vcn';
            app.example1 = uilabel(app.GridLayout);
            app.example1.Text = {'Example 1:  Determination the phase sequence of the set of voltages'; '* Sample values; 200cos(wt+10) ---> mag, anlge --> 200,10'; '                               5+2i, 350, -10i and etc.'};
            app.Sequences1 = uibuttongroup(app.GridLayout);
            app.Sequences1.Title = 'Sequences';
            app.abc1 = uiradiobutton(app.Sequences1);
            app.abc1.Text = 'Positive (abc) sequence';
            app.abc1.Value = true;
            app.acb1 = uiradiobutton(app.Sequences1);
            app.acb1.Text = 'Negative (acb) sequence';
            app.Ex2Tab = uitab(app.TabGroup);
            app.Ex2Tab.Title = 'Ex-2';
            app.DONTDELETELabel = uilabel(app.Ex2Tab);
            app.DONTDELETELabel.Text = 'DONT DELETE!';
            app.DONTDELETELabel_2 = uilabel(app.Ex2Tab);
            app.DONTDELETELabel_2.Text = 'DONT DELETE!';
            app.Image2 = uiimage(app.Ex2Tab);
            app.Image2.ImageSource = 'ch3-ex2.png';
            app.Vcn2 = uilabel(app.Ex2Tab);
            app.Vcn2.Text = 'Vcn';
            app.Vbn2 = uilabel(app.Ex2Tab);
            app.Vbn2.Text = 'Vbn';
            app.Z2a2 = uilabel(app.Ex2Tab);
            app.Z2a2.Text = 'Z1';
            app.Z2a1 = uilabel(app.Ex2Tab);
            app.Z2a1.Text = 'Z1';
            app.Z2b2 = uilabel(app.Ex2Tab);
            app.Z2b2.Text = 'Z2';
            app.Z2b1 = uilabel(app.Ex2Tab);
            app.Z2b1.Text = 'Z2';
            app.Sequences2 = uibuttongroup(app.Ex2Tab);
            app.Sequences2.Title = 'Sequences';
            app.abc2 = uiradiobutton(app.Sequences2);
            app.abc2.Text = 'Positive (abc) sequence';
            app.abc2.Value = true;
            app.acb2 = uiradiobutton(app.Sequences2);
            app.acb2.Text = 'Negative (acb) sequence';
            app.VanEditFieldLabel = uilabel(app.Ex2Tab);
            app.VanEditFieldLabel.Text = 'Van: ';
            app.Van2 = uieditfield(app.Ex2Tab, 'text');
            app.Van2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Van2.Placeholder = 'V';
            app.Z1EditFieldLabel = uilabel(app.Ex2Tab);
            app.Z1EditFieldLabel.Text = 'Z1: ';
            app.Z2a = uieditfield(app.Ex2Tab, 'text');
            app.Z2a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z2a.Placeholder = 'Ω';
            app.Z2EditFieldLabel = uilabel(app.Ex2Tab);
            app.Z2EditFieldLabel.Text = 'Z2: ';
            app.Z2b = uieditfield(app.Ex2Tab, 'text');
            app.Z2b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z2b.Placeholder = 'Ω';
            app.ex2_calculateButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex2_clearButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_clearButtonPushed, true);
            app.ex2_clearButton.Text = 'CLEAR';
            app.IaLabel = uilabel(app.Ex2Tab);
            app.IaLabel.Text = 'Ia: ';
            app.Ia2 = uieditfield(app.Ex2Tab, 'text');
            app.Ia2.Editable = 'off';
            app.Ia2.Placeholder = 'Ia';
            app.IaLabel_2 = uilabel(app.Ex2Tab);
            app.IaLabel_2.Text = 'Ib: ';
            app.Ib2 = uieditfield(app.Ex2Tab, 'text');
            app.Ib2.Editable = 'off';
            app.Ib2.Placeholder = 'Ib';
            app.IaLabel_3 = uilabel(app.Ex2Tab);
            app.IaLabel_3.Text = 'Ic: ';
            app.Ic2 = uieditfield(app.Ex2Tab, 'text');
            app.Ic2.Editable = 'off';
            app.Ic2.Placeholder = 'Ic';
            app.Vann2 = uilabel(app.Ex2Tab);
            app.Vann2.Text = 'Van';
            app.example2 = uilabel(app.Ex2Tab);
            app.example2.Text = {'Example 2: Calculation the line currents in the three-wire Y-Y balanced system of figure. (Van is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10'; '                              rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex3Tab = uitab(app.TabGroup);
            app.Ex3Tab.Title = 'Ex-3';
            app.Ima3 = uiimage(app.Ex3Tab);
            app.Ima3.ImageSource = 'ch3-ex3.png';
            app.VanEditField_2Label = uilabel(app.Ex3Tab);
            app.VanEditField_2Label.Text = 'Van: ';
            app.Van3 = uieditfield(app.Ex3Tab, 'text');
            app.Van3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Van3.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Van3.Placeholder = 'V';
            app.ZEditFieldLabel = uilabel(app.Ex3Tab);
            app.ZEditFieldLabel.Text = 'Z: ';
            app.Z3 = uieditfield(app.Ex3Tab, 'text');
            app.Z3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z3.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Z3.Placeholder = 'Ω';
            app.Vbn3 = uilabel(app.Ex3Tab);
            app.Vbn3.Text = 'Vbn';
            app.Vcn3 = uilabel(app.Ex3Tab);
            app.Vcn3.Text = 'Vcn';
            app.Z3b = uilabel(app.Ex3Tab);
            app.Z3b.Text = 'Z';
            app.Z3a = uilabel(app.Ex3Tab);
            app.Z3a.Text = 'Z';
            app.Vann3 = uilabel(app.Ex3Tab);
            app.Vann3.Text = 'Van';
            app.Sequences3 = uibuttongroup(app.Ex3Tab);
            app.Sequences3.Title = 'Sequences';
            app.abc3 = uiradiobutton(app.Sequences3);
            app.abc3.Text = 'Positive (abc) sequence';
            app.abc3.Value = true;
            app.acb3 = uiradiobutton(app.Sequences3);
            app.acb3.Text = 'Negative (acb) sequence';
            app.ex3_calculateButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_calculateButtonPushed, true);
            app.ex3_calculateButton.Text = 'CALCULATE';
            app.ex3_clearButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_clearButtonPushed, true);
            app.ex3_clearButton.Text = 'CLEAR';
            app.IaLabel_4 = uilabel(app.Ex3Tab);
            app.IaLabel_4.Text = 'I-AB: ';
            app.Iab3 = uieditfield(app.Ex3Tab, 'text');
            app.Iab3.Editable = 'off';
            app.Iab3.Placeholder = 'AB';
            app.IaLabel_5 = uilabel(app.Ex3Tab);
            app.IaLabel_5.Text = 'I-BC: ';
            app.Ibc3 = uieditfield(app.Ex3Tab, 'text');
            app.Ibc3.Editable = 'off';
            app.Ibc3.Placeholder = 'BC';
            app.IaLabel_6 = uilabel(app.Ex3Tab);
            app.IaLabel_6.Text = 'I-AC: ';
            app.Iac3 = uieditfield(app.Ex3Tab, 'text');
            app.Iac3.Editable = 'off';
            app.Iac3.Placeholder = 'AC';
            app.IaLabel_7 = uilabel(app.Ex3Tab);
            app.IaLabel_7.Text = 'Ia: ';
            app.Ia3 = uieditfield(app.Ex3Tab, 'text');
            app.Ia3.Editable = 'off';
            app.Ia3.Placeholder = 'Ia';
            app.IaLabel_8 = uilabel(app.Ex3Tab);
            app.IaLabel_8.Text = 'Ib: ';
            app.Ib3 = uieditfield(app.Ex3Tab, 'text');
            app.Ib3.Editable = 'off';
            app.Ib3.Placeholder = 'Ib';
            app.IaLabel_9 = uilabel(app.Ex3Tab);
            app.IaLabel_9.Text = 'Ic: ';
            app.Ic3 = uieditfield(app.Ex3Tab, 'text');
            app.Ic3.Editable = 'off';
            app.Ic3.Placeholder = 'Ic';
            app.example3 = uilabel(app.Ex3Tab);
            app.example3.Text = {'Example 3: Calculation the phase and line currents in the three-wire Y-Δ balanced system of figure. (Van is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10'; '                              rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex4Tab = uitab(app.TabGroup);
            app.Ex4Tab.Title = 'Ex-4';
            app.Image4 = uiimage(app.Ex4Tab);
            app.Image4.ImageSource = 'ch3-ex4.png';
            app.VanEditField_2Label_2 = uilabel(app.Ex4Tab);
            app.VanEditField_2Label_2.Text = 'Vab: ';
            app.Vab4 = uieditfield(app.Ex4Tab, 'text');
            app.Vab4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Vab4.Placeholder = 'V';
            app.ZEditFieldLabel_2 = uilabel(app.Ex4Tab);
            app.ZEditFieldLabel_2.Text = 'Z: ';
            app.Z4 = uieditfield(app.Ex4Tab, 'text');
            app.Z4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z4.Placeholder = 'Ω';
            app.Sequences4 = uibuttongroup(app.Ex4Tab);
            app.Sequences4.Title = 'Sequences';
            app.abc4 = uiradiobutton(app.Sequences4);
            app.abc4.Text = 'Positive (abc) sequence';
            app.abc4.Value = true;
            app.acb4 = uiradiobutton(app.Sequences4);
            app.acb4.Text = 'Negative (acb) sequence';
            app.ex4_calculateButton = uibutton(app.Ex4Tab, 'push');
            app.ex4_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_calculateButtonPushed, true);
            app.ex4_calculateButton.Text = 'CALCULATE';
            app.ex4_clearButton = uibutton(app.Ex4Tab, 'push');
            app.ex4_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_clearButtonPushed, true);
            app.ex4_clearButton.Text = 'CLEAR';
            app.IaLabel_10 = uilabel(app.Ex4Tab);
            app.IaLabel_10.Text = 'I-AB: ';
            app.Iab4 = uieditfield(app.Ex4Tab, 'text');
            app.Iab4.Editable = 'off';
            app.Iab4.Placeholder = 'AB';
            app.IaLabel_11 = uilabel(app.Ex4Tab);
            app.IaLabel_11.Text = 'I-BC: ';
            app.Ibc4 = uieditfield(app.Ex4Tab, 'text');
            app.Ibc4.Editable = 'off';
            app.Ibc4.Placeholder = 'BC';
            app.IaLabel_12 = uilabel(app.Ex4Tab);
            app.IaLabel_12.Text = 'I-AC: ';
            app.Iac4 = uieditfield(app.Ex4Tab, 'text');
            app.Iac4.Editable = 'off';
            app.Iac4.Placeholder = 'AC';
            app.IaLabel_13 = uilabel(app.Ex4Tab);
            app.IaLabel_13.Text = 'Ia: ';
            app.Ia4 = uieditfield(app.Ex4Tab, 'text');
            app.Ia4.Editable = 'off';
            app.Ia4.Placeholder = 'Ia';
            app.IaLabel_14 = uilabel(app.Ex4Tab);
            app.IaLabel_14.Text = 'Ib: ';
            app.Ib4 = uieditfield(app.Ex4Tab, 'text');
            app.Ib4.Editable = 'off';
            app.Ib4.Placeholder = 'Ib';
            app.IaLabel_15 = uilabel(app.Ex4Tab);
            app.IaLabel_15.Text = 'Ic: ';
            app.Ic4 = uieditfield(app.Ex4Tab, 'text');
            app.Ic4.Editable = 'off';
            app.Ic4.Placeholder = 'Ic';
            app.Vca4 = uilabel(app.Ex4Tab);
            app.Vca4.Text = 'Vca';
            app.Vbc4 = uilabel(app.Ex4Tab);
            app.Vbc4.Text = 'Vbc';
            app.Z4a = uilabel(app.Ex4Tab);
            app.Z4a.Text = 'Z';
            app.Z4b = uilabel(app.Ex4Tab);
            app.Z4b.Text = 'Z';
            app.Vba4 = uilabel(app.Ex4Tab);
            app.Vba4.Text = 'Vab';
            app.example4 = uilabel(app.Ex4Tab);
            app.example4.Text = {'Example 4: Calculation the phase and line currents in the three-wire Δ-Δ balanced system of figure. (Vab is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10'; '                              rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex5Tab = uitab(app.TabGroup);
            app.Ex5Tab.Title = 'Ex-5';
            app.Image5 = uiimage(app.Ex5Tab);
            app.Image5.ImageSource = 'ch3-ex5.png';
            app.Sequences5 = uibuttongroup(app.Ex5Tab);
            app.Sequences5.Title = 'Sequences';
            app.abc5 = uiradiobutton(app.Sequences5);
            app.abc5.Text = 'Positive (abc) sequence';
            app.abc5.Value = true;
            app.acb5 = uiradiobutton(app.Sequences5);
            app.acb5.Text = 'Negative (acb) sequence';
            app.ex5_calculateButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_calculateButtonPushed, true);
            app.ex5_calculateButton.Text = 'CALCULATE';
            app.ex5_clearButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_clearButtonPushed, true);
            app.ex5_clearButton.Text = 'CLEAR';
            app.Vca5 = uilabel(app.Ex5Tab);
            app.Vca5.Text = 'Vca';
            app.Vbc5 = uilabel(app.Ex5Tab);
            app.Vbc5.Text = 'Vbc';
            app.Z5a = uilabel(app.Ex5Tab);
            app.Z5a.Text = 'Z';
            app.Z5b = uilabel(app.Ex5Tab);
            app.Z5b.Text = 'Z';
            app.Vba5 = uilabel(app.Ex5Tab);
            app.Vba5.Text = 'Vab';
            app.VanEditField_2Label_3 = uilabel(app.Ex5Tab);
            app.VanEditField_2Label_3.Text = 'Vab: ';
            app.Vab5 = uieditfield(app.Ex5Tab, 'text');
            app.Vab5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Vab5.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Vab5.Placeholder = 'V';
            app.ZEditFieldLabel_3 = uilabel(app.Ex5Tab);
            app.ZEditFieldLabel_3.Text = 'Z: ';
            app.Z5 = uieditfield(app.Ex5Tab, 'text');
            app.Z5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z5.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Z5.Placeholder = 'Ω';
            app.IaLabel_19 = uilabel(app.Ex5Tab);
            app.IaLabel_19.Text = 'I-AB: ';
            app.Ia5 = uieditfield(app.Ex5Tab, 'text');
            app.Ia5.Editable = 'off';
            app.Ia5.Placeholder = 'Ia';
            app.IaLabel_20 = uilabel(app.Ex5Tab);
            app.IaLabel_20.Text = 'I-BC: ';
            app.Ib5 = uieditfield(app.Ex5Tab, 'text');
            app.Ib5.Editable = 'off';
            app.Ib5.Placeholder = 'Ib';
            app.IaLabel_21 = uilabel(app.Ex5Tab);
            app.IaLabel_21.Text = 'I-AC: ';
            app.Ic5 = uieditfield(app.Ex5Tab, 'text');
            app.Ic5.Editable = 'off';
            app.Ic5.Placeholder = 'Ic';
            app.example5 = uilabel(app.Ex5Tab);
            app.example5.Text = {'Example 5: Calculation the phase currents in the three-wire Δ-Y balanced system of figure. (Vab is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10'; '                              rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex6Tab = uitab(app.TabGroup);
            app.Ex6Tab.Title = 'Ex-6';
            app.Image6 = uiimage(app.Ex6Tab);
            app.Image6.ImageSource = 'ch3-ex6.png';
            app.Vcn6 = uilabel(app.Ex6Tab);
            app.Vcn6.Text = 'Vcn';
            app.Vbn6 = uilabel(app.Ex6Tab);
            app.Vbn6.Text = 'Vbn';
            app.Z6a1 = uilabel(app.Ex6Tab);
            app.Z6a1.Text = 'Z1';
            app.Z6a2 = uilabel(app.Ex6Tab);
            app.Z6a2.Text = 'Z1';
            app.Z6b1 = uilabel(app.Ex6Tab);
            app.Z6b1.Text = 'Z2';
            app.Z6b2 = uilabel(app.Ex6Tab);
            app.Z6b2.Text = 'Z2';
            app.Sequences6 = uibuttongroup(app.Ex6Tab);
            app.Sequences6.Title = 'Sequences';
            app.abc6 = uiradiobutton(app.Sequences6);
            app.abc6.Text = 'Positive (abc) sequence';
            app.abc6.Value = true;
            app.acb6 = uiradiobutton(app.Sequences6);
            app.acb6.Text = 'Negative (acb) sequence';
            app.ex6_calculateButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_calculateButtonPushed, true);
            app.ex6_calculateButton.Text = 'CALCULATE';
            app.ex6_clearButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_clearButtonPushed, true);
            app.ex6_clearButton.Text = 'CLEAR';
            app.Vann6 = uilabel(app.Ex6Tab);
            app.Vann6.Text = 'Van';
            app.VanEditFieldLabel_2 = uilabel(app.Ex6Tab);
            app.VanEditFieldLabel_2.Text = 'Van: ';
            app.Van6 = uieditfield(app.Ex6Tab, 'text');
            app.Van6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Van6.Placeholder = 'V';
            app.Z1EditFieldLabel_2 = uilabel(app.Ex6Tab);
            app.Z1EditFieldLabel_2.Text = 'Z1: ';
            app.Z6a = uieditfield(app.Ex6Tab, 'text');
            app.Z6a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z6a.Placeholder = 'pf ';
            app.Z2EditFieldLabel_2 = uilabel(app.Ex6Tab);
            app.Z2EditFieldLabel_2.Text = 'Z2: ';
            app.Z6b = uieditfield(app.Ex6Tab, 'text');
            app.Z6b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Z6b.Placeholder = 'pf ';
            app.IaLabel_22 = uilabel(app.Ex6Tab);
            app.IaLabel_22.Text = 'S-source: ';
            app.Ss6 = uieditfield(app.Ex6Tab, 'text');
            app.Ss6.Editable = 'off';
            app.Ss6.Placeholder = 'P, Q';
            app.IaLabel_23 = uilabel(app.Ex6Tab);
            app.IaLabel_23.Text = 'S-load: ';
            app.Slo6 = uieditfield(app.Ex6Tab, 'text');
            app.Slo6.Editable = 'off';
            app.Slo6.Placeholder = 'P, Q';
            app.IaLabel_24 = uilabel(app.Ex6Tab);
            app.IaLabel_24.Text = 'S-line: ';
            app.Sli6 = uieditfield(app.Ex6Tab, 'text');
            app.Sli6.Editable = 'off';
            app.Sli6.Placeholder = 'P, Q';
            app.IaLabel_25 = uilabel(app.Ex6Tab);
            app.IaLabel_25.Text = 'S-total: ';
            app.St6 = uieditfield(app.Ex6Tab, 'text');
            app.St6.Editable = 'off';
            app.St6.Placeholder = 'total';
            app.example6 = uilabel(app.Ex6Tab);
            app.example6.Text = {'Example 6: Determination total average power, reactive power and complex power '; 'in the three-wire Y-Y balanced system of figure. (Van is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10  rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex7Tab = uitab(app.TabGroup);
            app.Ex7Tab.Title = 'Ex-7';
            app.GridLayout2 = uigridlayout(app.Ex7Tab);
            app.ex7_calculateButton = uibutton(app.GridLayout2, 'push');
            app.ex7_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex7_calculateButtonPushed, true);
            app.ex7_calculateButton.Text = 'CALCULATE';
            app.ex7_clearButton = uibutton(app.GridLayout2, 'push');
            app.ex7_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex7_clearButtonPushed, true);
            app.ex7_clearButton.Text = 'CLEAR';
            app.ThepowerLabel = uilabel(app.GridLayout2);
            app.ThepowerLabel.Text = 'The power: ';
            app.p7 = uieditfield(app.GridLayout2, 'text');
            app.p7.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.p7.Placeholder = 'W';
            app.ThelinevoltageLabel = uilabel(app.GridLayout2);
            app.ThelinevoltageLabel.Text = 'The line voltage: ';
            app.v7 = uieditfield(app.GridLayout2, 'text');
            app.v7.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v7.Placeholder = 'V';
            app.ThelinecurrentLabel = uilabel(app.GridLayout2);
            app.ThelinecurrentLabel.Text = 'The line current: ';
            app.i7 = uieditfield(app.GridLayout2, 'text');
            app.i7.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.i7.Placeholder = 'A';
            app.PowerfactorEditFieldLabel = uilabel(app.GridLayout2);
            app.PowerfactorEditFieldLabel.Text = 'Power factor:';
            app.pf7 = uieditfield(app.GridLayout2, 'text');
            app.pf7.Editable = 'off';
            app.pf7.Placeholder = 'pf ';
            app.example7 = uilabel(app.GridLayout2);
            app.example7.Text = {'Example 7: A three-phase motor can be regarded as a '; 'balanced Y-load. A three-phase motor draws W '; 'when the line voltage is V,  and the line current is A. '; 'Determination the power factor of the motor.'; '* Sample values; '; 'polar form: 200cos(wt+10) ---> mag, anlge --> 200,10'; 'rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex8Tab = uitab(app.TabGroup);
            app.Ex8Tab.Title = 'Ex-8';
            app.Image8 = uiimage(app.Ex8Tab);
            app.Image8.ImageSource = 'ch3-ex8.png';
            app.VrmsLabel = uilabel(app.Ex8Tab);
            app.VrmsLabel.Text = 'Vrms: ';
            app.v8 = uieditfield(app.Ex8Tab, 'text');
            app.v8.Placeholder = 'V';
            app.FrequencyEditFieldLabel = uilabel(app.Ex8Tab);
            app.FrequencyEditFieldLabel.Text = 'Frequency: ';
            app.f8 = uieditfield(app.Ex8Tab, 'text');
            app.f8.Placeholder = 'Hz';
            app.Forload1Label = uilabel(app.Ex8Tab);
            app.Forload1Label.Text = 'For load 1;';
            app.laglead8a = uibuttongroup(app.Ex8Tab);
            app.laglead8a.Title = 'Lagging & Leading for L1';
            app.lag8a = uiradiobutton(app.laglead8a);
            app.lag8a.Text = 'Lagging';
            app.lag8a.Value = true;
            app.lead8a = uiradiobutton(app.laglead8a);
            app.lead8a.Text = 'Leading';
            app.Forload2Label = uilabel(app.Ex8Tab);
            app.Forload2Label.Text = 'For load 2;';
            app.laglead8b = uibuttongroup(app.Ex8Tab);
            app.laglead8b.Title = 'Lagging & Leading for L2';
            app.lag8b = uiradiobutton(app.laglead8b);
            app.lag8b.Text = 'Lagging';
            app.lag8b.Value = true;
            app.lead8b = uiradiobutton(app.laglead8b);
            app.lead8b.Text = 'Leading';
            app.Sequences8 = uibuttongroup(app.Ex8Tab);
            app.Sequences8.Title = 'Sequences';
            app.abc8 = uiradiobutton(app.Sequences8);
            app.abc8.Text = 'Positive (abc) sequence';
            app.abc8.Value = true;
            app.acb8 = uiradiobutton(app.Sequences8);
            app.acb8.Text = 'Negative (acb) sequence';
            app.VrmsLabel_2 = uilabel(app.Ex8Tab);
            app.VrmsLabel_2.Text = 'Reactive power (Q): ';
            app.q8a = uieditfield(app.Ex8Tab, 'text');
            app.q8a.Placeholder = 'kVAR';
            app.VrmsLabel_3 = uilabel(app.Ex8Tab);
            app.VrmsLabel_3.Text = 'Power factor: ';
            app.pf8a = uieditfield(app.Ex8Tab, 'text');
            app.pf8a.Placeholder = 'pf';
            app.VrmsLabel_4 = uilabel(app.Ex8Tab);
            app.VrmsLabel_4.Text = 'Reactive power (Q): ';
            app.q8b = uieditfield(app.Ex8Tab, 'text');
            app.q8b.Placeholder = 'kVAR';
            app.VrmsLabel_5 = uilabel(app.Ex8Tab);
            app.VrmsLabel_5.Text = 'Power factor: ';
            app.pf8b = uieditfield(app.Ex8Tab, 'text');
            app.pf8b.Placeholder = 'pf';
            app.ex8_calculateButton = uibutton(app.Ex8Tab, 'push');
            app.ex8_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex8_calculateButtonPushed, true);
            app.ex8_calculateButton.Text = 'CALCULATE';
            app.ex8_clearButton = uibutton(app.Ex8Tab, 'push');
            app.ex8_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex8_clearButtonPushed, true);
            app.ex8_clearButton.Text = 'CLEAR';
            app.example8 = uilabel(app.Ex8Tab);
            app.example8.Text = {'Example 8: Determination the complex, real, and reactive powers absorbed by the combined load, the line currents, '; 'and the kVAR rating of the three capacitors Δ-connected in parallel with the load that will raise the power'; 'factor and the capacitance of each capacitor. '; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10  rectangular form: 5+2i, 350, -10i and etc.'};
            app.laglead8b_2 = uibuttongroup(app.Ex8Tab);
            app.laglead8b_2.Title = 'Lagging & Leading';
            app.lag8b_2 = uiradiobutton(app.laglead8b_2);
            app.lag8b_2.Text = 'Lagging';
            app.lag8b_2.Value = true;
            app.lead8b_3 = uiradiobutton(app.laglead8b_2);
            app.lead8b_3.Text = 'Leading';
            app.VrmsLabel_10 = uilabel(app.Ex8Tab);
            app.VrmsLabel_10.Text = 'Power factor: ';
            app.pf8a_2 = uieditfield(app.Ex8Tab, 'text');
            app.pf8a_2.Placeholder = 'pf';
            app.DAHASONRAINCELELabel = uilabel(app.Ex8Tab);
            app.DAHASONRAINCELELabel.Text = 'DAHA SONRA INCELE!';
            app.Ex9Tab = uitab(app.TabGroup);
            app.Ex9Tab.Title = 'Ex-9';
            app.GridLayout3 = uigridlayout(app.Ex9Tab);
            app.Image9 = uiimage(app.GridLayout3);
            app.Image9.ImageSource = 'ch3-ex9.png';
            app.ex9_calculateButton = uibutton(app.GridLayout3, 'push');
            app.ex9_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex9_calculateButtonPushed, true);
            app.ex9_calculateButton.Text = 'CALCULATE';
            app.ex9_clearButton = uibutton(app.GridLayout3, 'push');
            app.ex9_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex9_clearButtonPushed, true);
            app.ex9_clearButton.Text = 'CLEAR';
            app.VrmsLabel_6 = uilabel(app.GridLayout3);
            app.VrmsLabel_6.Text = 'The voltage: ';
            app.v9 = uieditfield(app.GridLayout3, 'text');
            app.v9.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v9.Placeholder = 'V';
            app.VrmsLabel_7 = uilabel(app.GridLayout3);
            app.VrmsLabel_7.Text = 'Z-A: ';
            app.z9a = uieditfield(app.GridLayout3, 'text');
            app.z9a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z9a.Placeholder = 'Ω';
            app.VrmsLabel_8 = uilabel(app.GridLayout3);
            app.VrmsLabel_8.Text = 'Z-B: ';
            app.z9b = uieditfield(app.GridLayout3, 'text');
            app.z9b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z9b.Placeholder = 'Ω';
            app.VrmsLabel_9 = uilabel(app.GridLayout3);
            app.VrmsLabel_9.Text = 'Z-C: ';
            app.z9c = uieditfield(app.GridLayout3, 'text');
            app.z9c.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z9c.Placeholder = 'Ω';
            app.IaLabel_26 = uilabel(app.GridLayout3);
            app.IaLabel_26.Text = 'Ia: ';
            app.Ia9 = uieditfield(app.GridLayout3, 'text');
            app.Ia9.Editable = 'off';
            app.Ia9.Placeholder = 'Ia';
            app.IaLabel_27 = uilabel(app.GridLayout3);
            app.IaLabel_27.Text = 'Ib: ';
            app.Ib9 = uieditfield(app.GridLayout3, 'text');
            app.Ib9.Editable = 'off';
            app.Ib9.Placeholder = 'Ib';
            app.IaLabel_28 = uilabel(app.GridLayout3);
            app.IaLabel_28.Text = 'Ic: ';
            app.Ic9 = uieditfield(app.GridLayout3, 'text');
            app.Ic9.Editable = 'off';
            app.Ic9.Placeholder = 'Ic';
            app.IaLabel_29 = uilabel(app.GridLayout3);
            app.IaLabel_29.Text = 'The neutral current: ';
            app.In9 = uieditfield(app.GridLayout3, 'text');
            app.In9.Editable = 'off';
            app.In9.Placeholder = 'In';
            app.Sequences9 = uibuttongroup(app.GridLayout3);
            app.Sequences9.Title = 'Sequences';
            app.abc9 = uiradiobutton(app.Sequences9);
            app.abc9.Text = 'Positive (abc) sequence';
            app.abc9.Value = true;
            app.acb9 = uiradiobutton(app.Sequences9);
            app.acb9.Text = 'Negative (acb) sequence';
            app.example9 = uilabel(app.GridLayout3);
            app.example9.Text = {'Example 9: The unbalanced Y-load system of figure has balanced'; 'with voltage.  Calculation the line currents and the neutral current.'; '* Sample values; polar form: 200cos(wt+10) -> mag, anlge -> 200,10  '; '                              rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex10Tab = uitab(app.TabGroup);
            app.Ex10Tab.Title = 'Ex-10';
            app.Image10 = uiimage(app.Ex10Tab);
            app.Image10.ImageSource = 'ch3-ex10.png';
            app.Vcn10 = uilabel(app.Ex10Tab);
            app.Vcn10.Text = 'Vcn(rms)';
            app.Vbn10 = uilabel(app.Ex10Tab);
            app.Vbn10.Text = 'Vbn(rms)';
            app.Sequences10 = uibuttongroup(app.Ex10Tab);
            app.Sequences10.Title = 'Sequences';
            app.abc10 = uiradiobutton(app.Sequences10);
            app.abc10.Text = 'Positive (abc) sequence';
            app.abc10.Value = true;
            app.acb10 = uiradiobutton(app.Sequences10);
            app.acb10.Text = 'Negative (acb) sequence';
            app.ex10_calculateButton = uibutton(app.Ex10Tab, 'push');
            app.ex10_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex10_calculateButtonPushed, true);
            app.ex10_calculateButton.Text = 'CALCULATE';
            app.ex10_clearButton = uibutton(app.Ex10Tab, 'push');
            app.ex10_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex10_clearButtonPushed, true);
            app.ex10_clearButton.Text = 'CLEAR';
            app.Vann10 = uilabel(app.Ex10Tab);
            app.Vann10.Text = 'Van(rms)';
            app.VanLabel = uilabel(app.Ex10Tab);
            app.VanLabel.Text = 'Van(rms):  ';
            app.Van10 = uieditfield(app.Ex10Tab, 'text');
            app.Van10.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Van10.Placeholder = 'V';
            app.RLabel = uilabel(app.Ex10Tab);
            app.RLabel.Text = 'L:  ';
            app.L10 = uieditfield(app.Ex10Tab, 'text');
            app.L10.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.L10.Placeholder = 'Ω';
            app.RLabel_2 = uilabel(app.Ex10Tab);
            app.RLabel_2.Text = 'R:  ';
            app.R10 = uieditfield(app.Ex10Tab, 'text');
            app.R10.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.R10.Placeholder = 'Ω';
            app.RLabel_3 = uilabel(app.Ex10Tab);
            app.RLabel_3.Text = 'C:  ';
            app.C10 = uieditfield(app.Ex10Tab, 'text');
            app.C10.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.C10.Placeholder = 'Ω';
            app.IaLabel_30 = uilabel(app.Ex10Tab);
            app.IaLabel_30.Text = 'Ia: ';
            app.Ia10 = uieditfield(app.Ex10Tab, 'text');
            app.Ia10.Editable = 'off';
            app.Ia10.Placeholder = 'Ia';
            app.IaLabel_31 = uilabel(app.Ex10Tab);
            app.IaLabel_31.Text = 'Ib: ';
            app.Ib10 = uieditfield(app.Ex10Tab, 'text');
            app.Ib10.Editable = 'off';
            app.Ib10.Placeholder = 'Ib';
            app.IaLabel_32 = uilabel(app.Ex10Tab);
            app.IaLabel_32.Text = 'Ic: ';
            app.Ic10 = uieditfield(app.Ex10Tab, 'text');
            app.Ic10.Editable = 'off';
            app.Ic10.Placeholder = 'Ic';
            app.IaLabel_33 = uilabel(app.Ex10Tab);
            app.IaLabel_33.Text = 'Total complex power by the load: ';
            app.Sload10 = uieditfield(app.Ex10Tab, 'text');
            app.Sload10.Editable = 'off';
            app.Sload10.Placeholder = 'Sload';
            app.IaLabel_34 = uilabel(app.Ex10Tab);
            app.IaLabel_34.Text = 'Total complex power by the source: ';
            app.Ssource10 = uieditfield(app.Ex10Tab, 'text');
            app.Ssource10.Editable = 'off';
            app.Ssource10.Placeholder = 'Ssource';
            app.example10 = uilabel(app.Ex10Tab);
            app.example10.Text = {'Example 10: For the unbalanced circuit in figure. Calculations the line currents, the total complex power absorbed '; 'by the load, and the total complex power absorbed by the source. (Van is reference)'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10  rectangular form: 5+2i, 350, -10i and etc.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end