classdef Chapter_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Ex1Tab                     matlab.ui.container.Tab
        example1                   matlab.ui.control.Label
        voltages                   matlab.ui.control.Label
        impedances                 matlab.ui.control.Label
        TX1Label                   matlab.ui.control.Label
        ex1_clearButton            matlab.ui.control.Button
        ex1_calculateButton        matlab.ui.control.Button
        b4v1                       matlab.ui.control.EditField
        IaLabel_10                 matlab.ui.control.Label
        b3v1                       matlab.ui.control.EditField
        IaLabel_9                  matlab.ui.control.Label
        b2v1                       matlab.ui.control.EditField
        IaLabel_8                  matlab.ui.control.Label
        b1v1                       matlab.ui.control.EditField
        IaLabel_7                  matlab.ui.control.Label
        xz1                        matlab.ui.control.EditField
        IaLabel_6                  matlab.ui.control.Label
        rz1                        matlab.ui.control.EditField
        IaLabel_5                  matlab.ui.control.Label
        mz1                        matlab.ui.control.EditField
        IaLabel_4                  matlab.ui.control.Label
        t2z1                       matlab.ui.control.EditField
        IaLabel_3                  matlab.ui.control.Label
        t1z1                       matlab.ui.control.EditField
        IaLabel_2                  matlab.ui.control.Label
        gz1                        matlab.ui.control.EditField
        IaLabel                    matlab.ui.control.Label
        ThebaseapparentpowerEditFieldLabel  matlab.ui.control.Label
        B4                         matlab.ui.control.EditField
        Bus4EditFieldLabel         matlab.ui.control.Label
        B3                         matlab.ui.control.EditField
        Bus3EditFieldLabel         matlab.ui.control.Label
        B2                         matlab.ui.control.EditField
        Bus2EditFieldLabel         matlab.ui.control.Label
        B1                         matlab.ui.control.EditField
        Bus1EditFieldLabel         matlab.ui.control.Label
        Mv1                        matlab.ui.control.EditField
        Ms1                        matlab.ui.control.EditField
        Mz1                        matlab.ui.control.EditField
        T2v1_2                     matlab.ui.control.EditField
        T2v1                       matlab.ui.control.EditField
        T2s1                       matlab.ui.control.EditField
        T2z1                       matlab.ui.control.EditField
        Lx1                        matlab.ui.control.EditField
        Lr1                        matlab.ui.control.EditField
        T1v1_2                     matlab.ui.control.EditField
        T1v1                       matlab.ui.control.EditField
        T1s1                       matlab.ui.control.EditField
        T1z1                       matlab.ui.control.EditField
        Gv1                        matlab.ui.control.EditField
        Gs1                        matlab.ui.control.EditField
        Gz1                        matlab.ui.control.EditField
        Sbase1                     matlab.ui.control.EditField
        Image1                     matlab.ui.control.Image
        DONTDELETELabel_2          matlab.ui.control.Label
        DONTDELETELabel            matlab.ui.control.Label
        Ex2Tab                     matlab.ui.container.Tab
        example2                   matlab.ui.control.Label
        mz2                        matlab.ui.control.EditField
        IaLabel_15                 matlab.ui.control.Label
        t2z2                       matlab.ui.control.EditField
        IaLabel_14                 matlab.ui.control.Label
        liz2                       matlab.ui.control.EditField
        IaLabel_13                 matlab.ui.control.Label
        t1z2                       matlab.ui.control.EditField
        IaLabel_12                 matlab.ui.control.Label
        gz2                        matlab.ui.control.EditField
        IaLabel_11                 matlab.ui.control.Label
        ThebaseapparentpowerEditFieldLabel_2  matlab.ui.control.Label
        ex2_clearButton            matlab.ui.control.Button
        ex2_calculateButton        matlab.ui.control.Button
        MratingsLabel              matlab.ui.control.Label
        Sbase2                     matlab.ui.control.EditField
        Mx2                        matlab.ui.control.EditField
        Mr2                        matlab.ui.control.EditField
        Mv2                        matlab.ui.control.EditField
        Ms2                        matlab.ui.control.EditField
        T2x2                       matlab.ui.control.EditField
        T2ratingsLabel             matlab.ui.control.Label
        T2r2                       matlab.ui.control.EditField
        T2v2_2                     matlab.ui.control.EditField
        T2v2                       matlab.ui.control.EditField
        T2s2                       matlab.ui.control.EditField
        LratingsLabel              matlab.ui.control.Label
        Lx2                        matlab.ui.control.EditField
        Lr2                        matlab.ui.control.EditField
        T1x2                       matlab.ui.control.EditField
        T1ratingsLabel             matlab.ui.control.Label
        T1r2                       matlab.ui.control.EditField
        T1v2_2                     matlab.ui.control.EditField
        T1v2                       matlab.ui.control.EditField
        T1s2                       matlab.ui.control.EditField
        GratingsLabel              matlab.ui.control.Label
        Gx2                        matlab.ui.control.EditField
        Gr2                        matlab.ui.control.EditField
        Gv2                        matlab.ui.control.EditField
        Gs2                        matlab.ui.control.EditField
        Image2                     matlab.ui.control.Image
        Ex3Tab                     matlab.ui.container.Tab
        A3phLabel                  matlab.ui.control.Label
        m                          matlab.ui.control.Label
        a2                         matlab.ui.control.Label
        a1                         matlab.ui.control.Label
        t4                         matlab.ui.control.Label
        l2                         matlab.ui.control.Label
        t3                         matlab.ui.control.Label
        t2                         matlab.ui.control.Label
        l1                         matlab.ui.control.Label
        t1                         matlab.ui.control.Label
        g                          matlab.ui.control.Label
        ex3_clearButton            matlab.ui.control.Button
        ex3_calculateButton        matlab.ui.control.Button
        PowerfactorEditFieldLabel  matlab.ui.control.Label
        ALabel                     matlab.ui.control.Label
        Line2EditFieldLabel        matlab.ui.control.Label
        LagLead                    matlab.ui.container.ButtonGroup
        Leading3                   matlab.ui.control.RadioButton
        Lagging3                   matlab.ui.control.RadioButton
        Apf3                       matlab.ui.control.EditField
        Line1EditFieldLabel        matlab.ui.control.Label
        T4EditFieldLabel           matlab.ui.control.Label
        T3EditFieldLabel           matlab.ui.control.Label
        T2EditFieldLabel           matlab.ui.control.Label
        Av3                        matlab.ui.control.EditField
        As3                        matlab.ui.control.EditField
        T1EditFieldLabel           matlab.ui.control.Label
        L2x3                       matlab.ui.control.EditField
        L2v3                       matlab.ui.control.EditField
        L1x3                       matlab.ui.control.EditField
        L1v3                       matlab.ui.control.EditField
        T4x3                       matlab.ui.control.EditField
        T4v3                       matlab.ui.control.EditField
        T4s3                       matlab.ui.control.EditField
        T3x3                       matlab.ui.control.EditField
        T3v3                       matlab.ui.control.EditField
        T3s3                       matlab.ui.control.EditField
        T2x3                       matlab.ui.control.EditField
        T2v3                       matlab.ui.control.EditField
        T2s3                       matlab.ui.control.EditField
        T1x3                       matlab.ui.control.EditField
        T1v3                       matlab.ui.control.EditField
        T1s3                       matlab.ui.control.EditField
        GeneratorLabel_2           matlab.ui.control.Label
        Mx3                        matlab.ui.control.EditField
        Mv3                        matlab.ui.control.EditField
        Ms3                        matlab.ui.control.EditField
        ThebaseapparentpowerEditFieldLabel_3  matlab.ui.control.Label
        Gx3                        matlab.ui.control.EditField
        Gv3                        matlab.ui.control.EditField
        Gs3                        matlab.ui.control.EditField
        GeneratorLabel             matlab.ui.control.Label
        Sbase3                     matlab.ui.control.EditField
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
    end

    
    methods (Access = private)
        % function that converts polar to rectangular form
        function rectForm = polarToRect(app, event)
            value = event.Source.Value;
            splitOperator = split(value, ',');
            if length(splitOperator) == 2
                app.DONTDELETELabel.Text = 'rect func';
                a = value;
                a = split(a, ',');
                mag = eval(a{1,1});
                phaseAngle = eval(a{2,1});
                real = mag * cos(deg2rad(phaseAngle));
                real = round(real, 2);
                imag = mag * sin(deg2rad(phaseAngle));
                imag = round(imag, 2);
                rectForm = string(complex(real, imag));
            else
                rectForm = value;
            end
        end
        
        % function that converts rectangular to polar form
        function polarForm = rectToPolar(app, value)
            app.DONTDELETELabel_2.Text = 'polar func';
            a = string(value);
            mag = abs(eval(a));
            ang = rad2deg(angle(eval(a)));
            polarForm = '(' + string(round(mag, 2)) + ',' + string(round(ang, 2)) + ')'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: As3, Av3, B1, B2, B3, B4, Gr2, Gs1, Gs2, Gs3, 
        % Gv1, Gv2, Gv3, Gx2, Gx3, L1v3, L1x3, L1x3, L2v3, L2x3, Lr1, Lr2, 
        % Lx1, Lx2, Mr2, Ms1, Ms1, Ms2, Ms3, Mv1, Mv1, Mv2, Mv3, Mx2, Mx3, 
        % Sbase1, Sbase1, Sbase2, Sbase3, T1r2, T1s1, T1s2, T1s3, T1v1, 
        % T1v1_2, T1v2, T1v2_2, T1v3, T1x2, T1x3, T2r2, T2s1, T2s2, T2s3, 
        % T2v1, T2v1_2, T2v2, T2v2, T2v2_2, T2v3, T2x2, T2x3, T3s3, T3v3, 
        % T3x3, T4s3, T4v3, T4x3
        function polarForm(app, event)
            event.Source.Value = polarToRect(app, event);            
        end

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            % ----- INPUT VALUES -----
            Sbase = eval(app.Sbase1.Value);
            Gz = eval(app.Gz1.Value); % for generator;
            Gs = eval(app.Gs1.Value);
            Gv = eval(app.Gv1.Value);
            T1z = eval(app.T1z1.Value); % for tranformer1;
            T1s = eval(app.T1s1.Value);
            T1va = eval(app.T1v1.Value);
            T1vb = eval(app.T1v1_2.Value);
            R = eval(app.Lr1.Value); % line R
            X = eval(app.Lx1.Value); % line X
            T2z = eval(app.T2z1.Value); % for tranformer2;
            T2s = eval(app.T2s1.Value);
            T2va = eval(app.T2v1.Value);
            T2vb = eval(app.T2v1_2.Value);
            Mz = eval(app.Mz1.Value); % for motor;
            Ms = eval(app.Ms1.Value);
            Mv = eval(app.Mv1.Value);
            % bus voltages;
            bus1 = eval(app.B1.Value);
            bus2 = eval(app.B2.Value);
            bus3 = eval(app.B3.Value);
            bus4 = eval(app.B4.Value);
            
            % ----- CALCULATIONS -----
            % per unit new impedances
            Zg = (Gz / 100) * (Gv / T1va)^2 * (Sbase / Gs);
            Zt1 = (T1z / 100) * (T1vb / T2va)^2 * (Sbase / T1s);
            Zt2 = (T2z / 100) * (T2va / T1vb)^2 * (Sbase / T2s);
            Zm = (Mz / 100) * (Mv / T2vb)^2 * (Sbase / Ms);
            Zbase = (T1vb)^2 / Sbase;
            Zr = R / Zbase;
            Zx = X / Zbase;

            % per unit new voltages
            Vbus1 = bus1 / T1va;
            Vbus2 = bus2 / T1vb;
            Vbus3 = bus3 / T2va;
            Vbus4 = bus4 / T2vb;

            % print operations
            app.gz1.Value = string(Zg) + ' pu';
            app.t1z1.Value = string(Zt1) + ' pu';
            app.t2z1.Value = string(Zt2) + ' pu';
            app.mz1.Value = string(Zm) + ' pu';
            app.rz1.Value = string(Zr) + ' pu';
            app.xz1.Value = string(Zx) + ' pu';
            app.b1v1.Value = string(Vbus1) + ' pu';
            app.b2v1.Value = string(Vbus2) + ' pu';
            app.b3v1.Value = string(Vbus3) + ' pu';
            app.b4v1.Value = string(Vbus4) + ' pu';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.Sbase1.Value = '';
            app.Gz1.Value = '';
            app.Gs1.Value = '';
            app.Gv1.Value = '';
            app.T1z1.Value = '';
            app.T1s1.Value = '';
            app.T1v1.Value = '';
            app.T1v1_2.Value = '';
            app.Lr1.Value = '';
            app.Lx1.Value = '';
            app.T2z1.Value = '';
            app.T2s1.Value = '';
            app.T2v1.Value = '';
            app.T2v1_2.Value = '';
            app.Mz1.Value = '';
            app.Ms1.Value = '';
            app.Mv1.Value = '';
            app.B1.Value = '';
            app.B2.Value = '';
            app.B3.Value = '';
            app.B4.Value = '';
            app.gz1.Value = '';
            app.t1z1.Value = '';
            app.t2z1.Value = '';
            app.mz1.Value = '';
            app.rz1.Value = '';
            app.xz1.Value = '';
            app.b1v1.Value = '';
            app.b2v1.Value = '';
            app.b3v1.Value = '';
            app.b4v1.Value = '';
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % ----- INPUT VALUES -----
            Sbase = eval(app.Sbase2.Value);
            Gs = eval(app.Gs2.Value);
            Gv = eval(app.Gv2.Value);
            Gr = eval(app.Gr2.Value);
            Gx = eval(app.Gr2.Value);
            T1s = eval(app.T1s2.Value);
            T1va = eval(app.T1v2.Value);
            T1vb = eval(app.T1v2_2.Value);
            T1r = eval(app.T1r2.Value);
            T1x = eval(app.T1x2.Value);
            Lr = eval(app.Lr2.Value);
            Lx = eval(app.Lx2.Value);
            T2s = eval(app.T2s2.Value);
            T2va = eval(app.T2v2.Value);
            T2vb = eval(app.T2v2_2.Value);
            T2r = eval(app.T2r2.Value);
            T2x = eval(app.T2x2.Value);
            Ms = eval(app.Ms2.Value);
            Mv = eval(app.Mv2.Value);
            Mx = eval(app.Mx2.Value);
            Mr = eval(app.Mr2.Value);

            % ----- CALCULATIONS -----
            Vbase1 = Gv;
            Vbase2 = Vbase1 * (T1vb / T1va);
            Vbase3 = Vbase2 * (T2vb / T2va);

            % Zbase1 = Vbase1^2 / Sbase; % not need
            Zbase2 = Vbase2^2 / Sbase;
            % Zbase3 = Vbase3^2 / Sbase; % not need

            % per unit new impedances
            % for generator
            Zgr = Gx * (Gv / Vbase1)^2 * (Sbase / Gs);
            Zgx = Gr * (Gv / Vbase1)^2 * (Sbase / Gs);
            G = complex(Zgr, Zgx);
            % for tranformer 1
            Zt1x = T1x * (T1vb / Vbase2)^2 * (Sbase / T1s);
            Zt1r = T1r * (T1vb / Vbase2)^2 * (Sbase / T1s);
            T1 = complex(Zt1r, Zt1x);
            % for line
            Zlx = Lx / Zbase2;
            Zlr = Lr / Zbase2;
            L = complex(Zlr, Zlx);
            % for tranformer 2
            Zt2r = T2r * (T2vb / Vbase3)^2 * (Sbase / T2s);
            Zt2x = T2x * (T2vb / Vbase3)^2 * (Sbase / T2s);
            T2 = complex(Zt2r, Zt2x);
            % for motor
            Zmx = Mx * (Mv / Vbase3)^2 * (Sbase / Ms);
            Zmr = Mr * (Mv / Vbase3)^2 * (Sbase / Ms);
            M = complex(Zmr, Zmx);

            % print operations
            app.gz2.Value = string(G) + ' or ' + rectToPolar(app, G) + ' pu';
            app.t1z2.Value = string(T1) + ' or ' + rectToPolar(app, T1) + ' pu';
            app.liz2.Value = string(L) + ' or ' + rectToPolar(app, L) + ' pu';
            app.t2z2.Value = string(T2) + ' or ' + rectToPolar(app, T2) + ' pu';
            app.mz2.Value = string(M) + ' or ' + rectToPolar(app, M) + ' pu';
        end

        % Button pushed function: ex2_clearButton
        function ex2_clearButtonPushed(app, event)
            app.Sbase2.Value = '';
            app.Gs2.Value = '';
            app.Gv2.Value = '';
            app.Gr2.Value = '';
            app.Gr2.Value = '';
            app.T1s2.Value = '';
            app.T1v2.Value = '';
            app.T1v2_2.Value = '';
            app.T1r2.Value = '';
            app.T1x2.Value = '';
            app.Lr2.Value = '';
            app.Lx2.Value = '';
            app.T2s2.Value = '';
            app.T2v2.Value = '';
            app.T2v2_2.Value = '';
            app.T2r2.Value = '';
            app.T2x2.Value = '';
            app.Ms2.Value = '';
            app.Mv2.Value = '';
            app.Mx2.Value = '';
            app.Mr2.Value = '';
            app.gz2.Value = '';
            app.t1z2.Value = '';
            app.liz2.Value = '';
            app.t2z2.Value = '';
            app.mz2.Value = '';
        end

        % Button pushed function: ex3_calculateButton
        function ex3_calculateButtonPushed(app, event)
            % ----- INPUT VALUES -----
            Sbase = eval(app.Sbase3.Value);
            Gs = eval(app.Gs3.Value);
            Gv = eval(app.Gv3.Value);
            Gx = eval(app.Gx3.Value);
            Ms = eval(app.Ms3.Value);
            Mv = eval(app.Mv3.Value);
            Mx = eval(app.Mx3.Value);
            T1s = eval(app.T1s3.Value);
            T1v = eval(app.T1v3.Value);
            T1x = eval(app.T1x3.Value);
            T2s = eval(app.T2s3.Value);
            T2v = eval(app.T2v3.Value);
            T2x = eval(app.T2x3.Value);
            T3s = eval(app.T3s3.Value);
            T3v = eval(app.T3v3.Value);
            T3x = eval(app.T3x3.Value);
            T4s = eval(app.T4s3.Value);
            T4v = eval(app.T4v3.Value);
            T4x = eval(app.T4x3.Value);
            L1v = eval(app.L1v3.Value);
            L1x = eval(app.L1x3.Value);
            L2v = eval(app.L2v3.Value);
            L2x = eval(app.L2x3.Value);
            As = eval(app.As3.Value);
            Av = eval(app.Av3.Value);
            Apf = eval(app.Apf3.Value);

            % lagging or leading
            if app.Lagging3.Value == 1
                pf = rad2deg(acos(Apf));
            elseif app.Leading3.Value == 1
                pf = 360 - rad2deg(acos(Apf));
            end

            % ----- CALCULATIONS -----
            Vbase1 = T1v;
            Vbase2 = L1v;
            Vbase3 = L2v;
            Vbase4 = T4v;

            Zbase_li1 = Vbase2^2 / Sbase;
            Zbase_li2 = Vbase3^2 / Sbase;
            Zbase_load = Vbase4^2 / Sbase;
            x_li1 = L1x / Zbase_li1;
            x_li2 = L2x / Zbase_li2;
            
            S3_load = As * complex(cos(deg2rad(pf)), sin(deg2rad(pf)));
            Zact = (sqrt(3) * Av^2) / conj(S3_load);
            Zpu = Zact / Zbase_load;

            Xg = Gx * (Gv / Vbase1)^2 * (Sbase / Gs);
            Xt1 = T1x * (T1v / Vbase1)^2 * (Sbase / T1s);
            Xt2 = T2x * (T2v / Vbase2)^2 * (Sbase / T2s);
            Xt3 = T3x * (T3v / Vbase3)^2 * (Sbase / T3s);
            Xt4 = T4x * (T4v / Vbase4)^2 * (Sbase / T4s);
            Xm = Mx * (Mv / Vbase4)^2 * (Sbase / Ms);
            
            Xli1 = complex(0, x_li1);
            Xli2 = complex(0, x_li2);
            Xg = complex(0, Xg);
            Xt1 = complex(0, Xt1);
            Xt2 = complex(0, Xt2);
            Xt3 = complex(0, Xt3);
            Xt4 = complex(0, Xt4);
            Xm = complex(0, Xm);

            % print operations
            app.g.Text = string(round(Xg, 2)) + ' pu';
            app.t1.Text = string(round(Xt1, 2)) + ' pu';
            app.l1.Text = string(round(Xli1, 2)) + ' pu';
            app.t2.Text = string(round(Xt2, 2)) + ' pu';
            app.t3.Text = string(round(Xt3, 2)) + ' pu';
            app.l2.Text = string(round(Xli2, 2)) + ' pu';
            app.t4.Text = string(round(Xt4, 2)) + ' pu';
            app.m.Text = string(round(Xm, 2)) + ' pu';
            app.a1.Text = string(real(Zpu)) + ' pu';
            app.a2.Text = string(imag(Zpu)) + ' pu';
        end

        % Button pushed function: ex3_clearButton
        function ex3_clearButtonPushed(app, event)
            app.Sbase3.Value = '';
            app.Gs3.Value = '';
            app.Gv3.Value = '';
            app.Gx3.Value = '';
            app.Ms3.Value = '';
            app.Mv3.Value = '';
            app.Mx3.Value = '';
            app.T1s3.Value = '';
            app.T1v3.Value = '';
            app.T1x3.Value = '';
            app.T2s3.Value = '';
            app.T2v3.Value = '';
            app.T2x3.Value = '';
            app.T3s3.Value = '';
            app.T3v3.Value = '';
            app.T3x3.Value = '';
            app.T4s3.Value = '';
            app.T4v3.Value = '';
            app.T4x3.Value = '';
            app.L1v3.Value = '';
            app.L1x3.Value = '';
            app.L2v3.Value = '';
            app.L2x3.Value = '';
            app.As3.Value = '';
            app.Av3.Value = '';
            app.Apf3.Value = '';
            app.g.Text = 'g';
            app.t1.Text ='t1';
            app.l1.Text = 'l1';
            app.t2.Text = 't2';
            app.t3.Text = 't3';
            app.l2.Text = 'l2';
            app.t4.Text = 't4';
            app.m.Text = 'm';
            app.a1.Text = 'a1';
            app.a2.Text = 'a2';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex1Tab = uitab(app.TabGroup);
            app.Ex1Tab.Title = 'Ex-1';
            app.DONTDELETELabel = uilabel(app.Ex1Tab);
            app.DONTDELETELabel.Text = 'DONT DELETE!';
            app.DONTDELETELabel_2 = uilabel(app.Ex1Tab);
            app.DONTDELETELabel_2.Text = 'DONT DELETE!';
            app.Image1 = uiimage(app.Ex1Tab);
            app.Image1.ImageSource = 'ch4-ex1.png';
            app.Sbase1 = uieditfield(app.Ex1Tab, 'text');
            app.Sbase1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Sbase1.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Sbase1.Placeholder = 'MVA';
            app.Gz1 = uieditfield(app.Ex1Tab, 'text');
            app.Gz1.Placeholder = 'Z(%)';
            app.Gs1 = uieditfield(app.Ex1Tab, 'text');
            app.Gs1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gs1.Placeholder = 'MVA';
            app.Gv1 = uieditfield(app.Ex1Tab, 'text');
            app.Gv1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gv1.Placeholder = 'kV';
            app.T1z1 = uieditfield(app.Ex1Tab, 'text');
            app.T1z1.Placeholder = 'Z(%)';
            app.T1s1 = uieditfield(app.Ex1Tab, 'text');
            app.T1s1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1s1.Placeholder = 'MVA';
            app.T1v1 = uieditfield(app.Ex1Tab, 'text');
            app.T1v1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1v1.Placeholder = 'kV';
            app.T1v1_2 = uieditfield(app.Ex1Tab, 'text');
            app.T1v1_2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1v1_2.Placeholder = 'kV';
            app.Lr1 = uieditfield(app.Ex1Tab, 'text');
            app.Lr1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Lr1.Placeholder = 'R(Ω)';
            app.Lx1 = uieditfield(app.Ex1Tab, 'text');
            app.Lx1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Lx1.Placeholder = 'X(Ω)';
            app.T2z1 = uieditfield(app.Ex1Tab, 'text');
            app.T2z1.Placeholder = 'Z(%)';
            app.T2s1 = uieditfield(app.Ex1Tab, 'text');
            app.T2s1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2s1.Placeholder = 'MVA';
            app.T2v1 = uieditfield(app.Ex1Tab, 'text');
            app.T2v1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v1.Placeholder = 'kV';
            app.T2v1_2 = uieditfield(app.Ex1Tab, 'text');
            app.T2v1_2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v1_2.Placeholder = 'kV';
            app.Mz1 = uieditfield(app.Ex1Tab, 'text');
            app.Mz1.Placeholder = 'Z(%)';
            app.Ms1 = uieditfield(app.Ex1Tab, 'text');
            app.Ms1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Ms1.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Ms1.Placeholder = 'MVA';
            app.Mv1 = uieditfield(app.Ex1Tab, 'text');
            app.Mv1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mv1.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.Mv1.Placeholder = 'kV';
            app.Bus1EditFieldLabel = uilabel(app.Ex1Tab);
            app.Bus1EditFieldLabel.Text = 'Bus1: ';
            app.B1 = uieditfield(app.Ex1Tab, 'text');
            app.B1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.B1.Placeholder = 'kV';
            app.Bus2EditFieldLabel = uilabel(app.Ex1Tab);
            app.Bus2EditFieldLabel.Text = 'Bus2: ';
            app.B2 = uieditfield(app.Ex1Tab, 'text');
            app.B2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.B2.Placeholder = 'kV';
            app.Bus3EditFieldLabel = uilabel(app.Ex1Tab);
            app.Bus3EditFieldLabel.Text = 'Bus3: ';
            app.B3 = uieditfield(app.Ex1Tab, 'text');
            app.B3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.B3.Placeholder = 'kV';
            app.Bus4EditFieldLabel = uilabel(app.Ex1Tab);
            app.Bus4EditFieldLabel.Text = 'Bus4: ';
            app.B4 = uieditfield(app.Ex1Tab, 'text');
            app.B4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.B4.Placeholder = 'kV';
            app.ThebaseapparentpowerEditFieldLabel = uilabel(app.Ex1Tab);
            app.ThebaseapparentpowerEditFieldLabel.Text = 'The base apparent power: ';
            app.IaLabel = uilabel(app.Ex1Tab);
            app.IaLabel.Text = 'Generator: ';
            app.gz1 = uieditfield(app.Ex1Tab, 'text');
            app.gz1.Editable = 'off';
            app.gz1.Placeholder = 'Zpu';
            app.IaLabel_2 = uilabel(app.Ex1Tab);
            app.IaLabel_2.Text = 'Transformer 1: ';
            app.t1z1 = uieditfield(app.Ex1Tab, 'text');
            app.t1z1.Editable = 'off';
            app.t1z1.Placeholder = 'Zpu';
            app.IaLabel_3 = uilabel(app.Ex1Tab);
            app.IaLabel_3.Text = 'Transformer 2: ';
            app.t2z1 = uieditfield(app.Ex1Tab, 'text');
            app.t2z1.Editable = 'off';
            app.t2z1.Placeholder = 'Zpu';
            app.IaLabel_4 = uilabel(app.Ex1Tab);
            app.IaLabel_4.Text = 'Motor: ';
            app.mz1 = uieditfield(app.Ex1Tab, 'text');
            app.mz1.Editable = 'off';
            app.mz1.Placeholder = 'Zpu';
            app.IaLabel_5 = uilabel(app.Ex1Tab);
            app.IaLabel_5.Text = 'R: ';
            app.rz1 = uieditfield(app.Ex1Tab, 'text');
            app.rz1.Editable = 'off';
            app.rz1.Placeholder = 'Zpu';
            app.IaLabel_6 = uilabel(app.Ex1Tab);
            app.IaLabel_6.Text = 'X: ';
            app.xz1 = uieditfield(app.Ex1Tab, 'text');
            app.xz1.Editable = 'off';
            app.xz1.Placeholder = 'Zpu';
            app.IaLabel_7 = uilabel(app.Ex1Tab);
            app.IaLabel_7.Text = 'Bus 1: ';
            app.b1v1 = uieditfield(app.Ex1Tab, 'text');
            app.b1v1.Editable = 'off';
            app.b1v1.Placeholder = 'Vpu';
            app.IaLabel_8 = uilabel(app.Ex1Tab);
            app.IaLabel_8.Text = 'Bus 2: ';
            app.b2v1 = uieditfield(app.Ex1Tab, 'text');
            app.b2v1.Editable = 'off';
            app.b2v1.Placeholder = 'Vpu';
            app.IaLabel_9 = uilabel(app.Ex1Tab);
            app.IaLabel_9.Text = 'Bus 3: ';
            app.b3v1 = uieditfield(app.Ex1Tab, 'text');
            app.b3v1.Editable = 'off';
            app.b3v1.Placeholder = 'Vpu';
            app.IaLabel_10 = uilabel(app.Ex1Tab);
            app.IaLabel_10.Text = 'Bus 4: ';
            app.b4v1 = uieditfield(app.Ex1Tab, 'text');
            app.b4v1.Editable = 'off';
            app.b4v1.Placeholder = 'Vpu';
            app.ex1_calculateButton = uibutton(app.Ex1Tab, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.Ex1Tab, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.TX1Label = uilabel(app.Ex1Tab);
            app.TX1Label.Text = 'TX1';
            app.impedances = uilabel(app.Ex1Tab);
            app.impedances.Text = 'Per unit new impedances;';
            app.voltages = uilabel(app.Ex1Tab);
            app.voltages.Text = 'Per unit new voltages;';
            app.example1 = uilabel(app.Ex1Tab);
            app.example1.Text = {'Example 1: Calculation the per unit values at each point of the system.'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10  rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex2Tab = uitab(app.TabGroup);
            app.Ex2Tab.Title = 'Ex-2';
            app.Image2 = uiimage(app.Ex2Tab);
            app.Image2.ImageSource = 'ch4-ex2.png';
            app.Gs2 = uieditfield(app.Ex2Tab, 'text');
            app.Gs2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gs2.Placeholder = 'MVA';
            app.Gv2 = uieditfield(app.Ex2Tab, 'text');
            app.Gv2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gv2.Placeholder = 'kV';
            app.Gr2 = uieditfield(app.Ex2Tab, 'text');
            app.Gr2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gr2.Placeholder = 'Rpu';
            app.Gx2 = uieditfield(app.Ex2Tab, 'text');
            app.Gx2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gx2.Placeholder = 'Xpu';
            app.GratingsLabel = uilabel(app.Ex2Tab);
            app.GratingsLabel.Text = 'G ratings;';
            app.T1s2 = uieditfield(app.Ex2Tab, 'text');
            app.T1s2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1s2.Placeholder = 'MVA';
            app.T1v2 = uieditfield(app.Ex2Tab, 'text');
            app.T1v2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1v2.Placeholder = 'kV';
            app.T1v2_2 = uieditfield(app.Ex2Tab, 'text');
            app.T1v2_2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1v2_2.Placeholder = 'kV';
            app.T1r2 = uieditfield(app.Ex2Tab, 'text');
            app.T1r2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1r2.Placeholder = 'Rpu';
            app.T1ratingsLabel = uilabel(app.Ex2Tab);
            app.T1ratingsLabel.Text = 'T1 ratings;';
            app.T1x2 = uieditfield(app.Ex2Tab, 'text');
            app.T1x2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1x2.Placeholder = 'Xpu';
            app.Lr2 = uieditfield(app.Ex2Tab, 'text');
            app.Lr2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Lr2.Placeholder = 'Rpu';
            app.Lx2 = uieditfield(app.Ex2Tab, 'text');
            app.Lx2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Lx2.Placeholder = 'Xpu';
            app.LratingsLabel = uilabel(app.Ex2Tab);
            app.LratingsLabel.Text = 'L ratings;';
            app.T2s2 = uieditfield(app.Ex2Tab, 'text');
            app.T2s2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2s2.Placeholder = 'MVA';
            app.T2v2 = uieditfield(app.Ex2Tab, 'text');
            app.T2v2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v2.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v2.Placeholder = 'kV';
            app.T2v2_2 = uieditfield(app.Ex2Tab, 'text');
            app.T2v2_2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v2_2.Placeholder = 'kV';
            app.T2r2 = uieditfield(app.Ex2Tab, 'text');
            app.T2r2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2r2.Placeholder = 'Rpu';
            app.T2ratingsLabel = uilabel(app.Ex2Tab);
            app.T2ratingsLabel.Text = 'T2 ratings;';
            app.T2x2 = uieditfield(app.Ex2Tab, 'text');
            app.T2x2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2x2.Placeholder = 'Xpu';
            app.Ms2 = uieditfield(app.Ex2Tab, 'text');
            app.Ms2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Ms2.Placeholder = 'MVA';
            app.Mv2 = uieditfield(app.Ex2Tab, 'text');
            app.Mv2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mv2.Placeholder = 'kV';
            app.Mr2 = uieditfield(app.Ex2Tab, 'text');
            app.Mr2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mr2.Placeholder = 'Rpu';
            app.Mx2 = uieditfield(app.Ex2Tab, 'text');
            app.Mx2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mx2.Placeholder = 'Xpu';
            app.Sbase2 = uieditfield(app.Ex2Tab, 'text');
            app.Sbase2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Sbase2.Placeholder = 'MVA';
            app.MratingsLabel = uilabel(app.Ex2Tab);
            app.MratingsLabel.Text = 'M ratings;';
            app.ex2_calculateButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex2_clearButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_clearButtonPushed, true);
            app.ex2_clearButton.Text = 'CLEAR';
            app.ThebaseapparentpowerEditFieldLabel_2 = uilabel(app.Ex2Tab);
            app.ThebaseapparentpowerEditFieldLabel_2.Text = 'The base apparent power: ';
            app.IaLabel_11 = uilabel(app.Ex2Tab);
            app.IaLabel_11.Text = 'The generator impedance: ';
            app.gz2 = uieditfield(app.Ex2Tab, 'text');
            app.gz2.Editable = 'off';
            app.gz2.Placeholder = 'R+jX';
            app.IaLabel_12 = uilabel(app.Ex2Tab);
            app.IaLabel_12.Text = 'The transformer 1 impedance: ';
            app.t1z2 = uieditfield(app.Ex2Tab, 'text');
            app.t1z2.Editable = 'off';
            app.t1z2.Placeholder = 'R+jX';
            app.IaLabel_13 = uilabel(app.Ex2Tab);
            app.IaLabel_13.Text = 'The line impedance: ';
            app.liz2 = uieditfield(app.Ex2Tab, 'text');
            app.liz2.Editable = 'off';
            app.liz2.Placeholder = 'R+jX';
            app.IaLabel_14 = uilabel(app.Ex2Tab);
            app.IaLabel_14.Text = 'The transformer 2 impedance: ';
            app.t2z2 = uieditfield(app.Ex2Tab, 'text');
            app.t2z2.Editable = 'off';
            app.t2z2.Placeholder = 'R+jX';
            app.IaLabel_15 = uilabel(app.Ex2Tab);
            app.IaLabel_15.Text = 'The motor impedance: ';
            app.mz2 = uieditfield(app.Ex2Tab, 'text');
            app.mz2.Editable = 'off';
            app.mz2.Placeholder = 'R+jX';
            app.example2 = uilabel(app.Ex2Tab);
            app.example2.Text = {'Example 2: A simple power system consisting of one synchronous generator and one synchronous motor connected by two transformers '; 'and a transmission line is shown in the following figure. Determination a perphase, per unit equivalent system for this power system '; 'using a base apparent power and a base line voltage at generator G1.'; '* Sample values; polar form: 200cos(wt+10) ---> mag, anlge --> 200,10  rectangular form: 5+2i, 350, -10i and etc.'};
            app.Ex3Tab = uitab(app.TabGroup);
            app.Ex3Tab.Title = 'Ex-3';
            app.Image3 = uiimage(app.Ex3Tab);
            app.Image3.ImageSource = 'ch4-ex3.png';
            app.Image4 = uiimage(app.Ex3Tab);
            app.Image4.ImageSource = 'ch4-ex3a.png';
            app.Sbase3 = uieditfield(app.Ex3Tab, 'text');
            app.Sbase3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Sbase3.Placeholder = 'MVA';
            app.GeneratorLabel = uilabel(app.Ex3Tab);
            app.GeneratorLabel.Text = 'Generator:  ';
            app.Gs3 = uieditfield(app.Ex3Tab, 'text');
            app.Gs3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gs3.Placeholder = 'MVA';
            app.Gv3 = uieditfield(app.Ex3Tab, 'text');
            app.Gv3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gv3.Placeholder = 'kV';
            app.Gx3 = uieditfield(app.Ex3Tab, 'text');
            app.Gx3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Gx3.Placeholder = 'pu';
            app.ThebaseapparentpowerEditFieldLabel_3 = uilabel(app.Ex3Tab);
            app.ThebaseapparentpowerEditFieldLabel_3.Text = 'The base apparent power: ';
            app.Ms3 = uieditfield(app.Ex3Tab, 'text');
            app.Ms3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Ms3.Placeholder = 'MVA';
            app.Mv3 = uieditfield(app.Ex3Tab, 'text');
            app.Mv3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mv3.Placeholder = 'kV';
            app.Mx3 = uieditfield(app.Ex3Tab, 'text');
            app.Mx3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Mx3.Placeholder = 'pu';
            app.GeneratorLabel_2 = uilabel(app.Ex3Tab);
            app.GeneratorLabel_2.Text = 'Motor:  ';
            app.T1s3 = uieditfield(app.Ex3Tab, 'text');
            app.T1s3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1s3.Placeholder = 'MVA';
            app.T1v3 = uieditfield(app.Ex3Tab, 'text');
            app.T1v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1v3.Placeholder = 'kV';
            app.T1x3 = uieditfield(app.Ex3Tab, 'text');
            app.T1x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T1x3.Placeholder = 'pu';
            app.T2s3 = uieditfield(app.Ex3Tab, 'text');
            app.T2s3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2s3.Placeholder = 'MVA';
            app.T2v3 = uieditfield(app.Ex3Tab, 'text');
            app.T2v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2v3.Placeholder = 'kV';
            app.T2x3 = uieditfield(app.Ex3Tab, 'text');
            app.T2x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T2x3.Placeholder = 'pu';
            app.T3s3 = uieditfield(app.Ex3Tab, 'text');
            app.T3s3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T3s3.Placeholder = 'MVA';
            app.T3v3 = uieditfield(app.Ex3Tab, 'text');
            app.T3v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T3v3.Placeholder = 'kV';
            app.T3x3 = uieditfield(app.Ex3Tab, 'text');
            app.T3x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T3x3.Placeholder = 'pu';
            app.T4s3 = uieditfield(app.Ex3Tab, 'text');
            app.T4s3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T4s3.Placeholder = 'MVA';
            app.T4v3 = uieditfield(app.Ex3Tab, 'text');
            app.T4v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T4v3.Placeholder = 'kV';
            app.T4x3 = uieditfield(app.Ex3Tab, 'text');
            app.T4x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.T4x3.Placeholder = 'pu';
            app.L1v3 = uieditfield(app.Ex3Tab, 'text');
            app.L1v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.L1v3.Placeholder = 'kv';
            app.L1x3 = uieditfield(app.Ex3Tab, 'text');
            app.L1x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.L1x3.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.L1x3.Placeholder = 'Ω';
            app.L2v3 = uieditfield(app.Ex3Tab, 'text');
            app.L2v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.L2v3.Placeholder = 'kV';
            app.L2x3 = uieditfield(app.Ex3Tab, 'text');
            app.L2x3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.L2x3.Placeholder = 'Ω';
            app.T1EditFieldLabel = uilabel(app.Ex3Tab);
            app.T1EditFieldLabel.Text = 'T1:  ';
            app.As3 = uieditfield(app.Ex3Tab, 'text');
            app.As3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.As3.Placeholder = 'MVA';
            app.Av3 = uieditfield(app.Ex3Tab, 'text');
            app.Av3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Av3.Placeholder = 'kV';
            app.T2EditFieldLabel = uilabel(app.Ex3Tab);
            app.T2EditFieldLabel.Text = 'T2:  ';
            app.T3EditFieldLabel = uilabel(app.Ex3Tab);
            app.T3EditFieldLabel.Text = 'T3:  ';
            app.T4EditFieldLabel = uilabel(app.Ex3Tab);
            app.T4EditFieldLabel.Text = 'T4:  ';
            app.Line1EditFieldLabel = uilabel(app.Ex3Tab);
            app.Line1EditFieldLabel.Text = 'Line 1:  ';
            app.Apf3 = uieditfield(app.Ex3Tab, 'text');
            app.Apf3.Placeholder = 'pf';
            app.LagLead = uibuttongroup(app.Ex3Tab);
            app.LagLead.Title = 'Lag & Lead';
            app.Lagging3 = uiradiobutton(app.LagLead);
            app.Lagging3.Text = 'Lagging';
            app.Lagging3.Value = true;
            app.Leading3 = uiradiobutton(app.LagLead);
            app.Leading3.Text = 'Leading';
            app.Line2EditFieldLabel = uilabel(app.Ex3Tab);
            app.Line2EditFieldLabel.Text = 'Line 2:  ';
            app.ALabel = uilabel(app.Ex3Tab);
            app.ALabel.Text = 'A:  ';
            app.PowerfactorEditFieldLabel = uilabel(app.Ex3Tab);
            app.PowerfactorEditFieldLabel.Text = 'Power factor:  ';
            app.ex3_calculateButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_calculateButtonPushed, true);
            app.ex3_calculateButton.Text = 'CALCULATE';
            app.ex3_clearButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_clearButtonPushed, true);
            app.ex3_clearButton.Text = 'CLEAR';
            app.g = uilabel(app.Ex3Tab);
            app.g.Text = 'g';
            app.t1 = uilabel(app.Ex3Tab);
            app.t1.Text = 't1';
            app.l1 = uilabel(app.Ex3Tab);
            app.l1.Text = 'l1';
            app.t2 = uilabel(app.Ex3Tab);
            app.t2.Text = 't2';
            app.t3 = uilabel(app.Ex3Tab);
            app.t3.Text = 't3';
            app.l2 = uilabel(app.Ex3Tab);
            app.l2.Text = 'l2';
            app.t4 = uilabel(app.Ex3Tab);
            app.t4.Text = 't4';
            app.a1 = uilabel(app.Ex3Tab);
            app.a1.Text = 'a1';
            app.a2 = uilabel(app.Ex3Tab);
            app.a2.Text = 'a2';
            app.m = uilabel(app.Ex3Tab);
            app.m.Text = 'm';
            app.A3phLabel = uilabel(app.Ex3Tab);
            app.A3phLabel.Text = 'A(3-ph)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end