classdef Chapter_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Ex1Tab                         matlab.ui.container.Tab
        C1                             matlab.ui.control.EditField
        ThecapacitanceperphaseLabel    matlab.ui.control.Label
        L1                             matlab.ui.control.EditField
        TheinductanceperphaseLabel     matlab.ui.control.Label
        ex1_clearButton                matlab.ui.control.Button
        ex1_calculateButton            matlab.ui.control.Button
        d131                           matlab.ui.control.EditField
        d231                           matlab.ui.control.EditField
        d121                           matlab.ui.control.EditField
        gmr1                           matlab.ui.control.EditField
        GeometricMeanRadiusGMREditFieldLabel  matlab.ui.control.Label
        d1                             matlab.ui.control.EditField
        DiameteroftheconductorEditFieldLabel  matlab.ui.control.Label
        Image1                         matlab.ui.control.Image
        Example1                       matlab.ui.control.Label
        Ex2Tab                         matlab.ui.container.Tab
        C2                             matlab.ui.control.EditField
        ThecapacitanceperphaseLabel_2  matlab.ui.control.Label
        L2                             matlab.ui.control.EditField
        TheinductanceperphaseLabel_2   matlab.ui.control.Label
        ex2_clearButton                matlab.ui.control.Button
        ex2_calculateButton            matlab.ui.control.Button
        d132                           matlab.ui.control.EditField
        d232                           matlab.ui.control.EditField
        d122                           matlab.ui.control.EditField
        bs2                            matlab.ui.control.EditField
        BundlespacingXLabel            matlab.ui.control.Label
        gmr2                           matlab.ui.control.EditField
        GeometricMeanRadiusGMREditFieldLabel_2  matlab.ui.control.Label
        d2                             matlab.ui.control.EditField
        DiameteroftheconductorEditFieldLabel_2  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Example2                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            % input values
            D = eval(app.d1.Value); % conductor diameter
            gmr = eval(app.gmr1.Value); % GMR
            d12 = eval(app.d121.Value); % D12
            d23 = eval(app.d231.Value); % D23
            d13 = eval(app.d131.Value); % D13

            % ---- CALCULATIONS ----
            r = D / (2 * 12); % conductor radius in feet
            gmrL = gmr / 12; % GMRl in feet
            gmd = (d12 * d23 * d13)^(1/3); % GMD in feet
            
            L = 0.2 * log(gmd / gmrL);
            C = 0.0556 / log(gmd / r);

            % print operations
            app.L1.Value = string(L) + ' mH/km';
            app.C1.Value = string(C) + ' μF/km';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.d1.Value = '';
            app.gmr1.Value = '';
            app.d121.Value = '';
            app.d231.Value = '';
            app.d131.Value = '';
            app.L1.Value = '';
            app.C1.Value = '';
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % input values
            D = eval(app.d2.Value); % conductor diameter
            gmr = eval(app.gmr2.Value); % GMR
            bs = eval(app.bs2.Value); % bundle spacing
            d12 = eval(app.d122.Value); % D12
            d23 = eval(app.d232.Value); % D23
            d13 = eval(app.d132.Value); % D13

            % ---- CALCULATIONS ----
            r = D / 2; % conductor radius in inch
            gmd = (d12 * d23 * d13)^(1/3); % GMD in feet
            gmrL = sqrt(gmr * bs) / 12; % GMRl in feet
            gmrC = sqrt(bs * r) / 12; % GMRc in feet
            
            L = 0.2 * log(gmd / gmrL);
            C = 0.0556 / log(gmd / gmrC);

            % print operations
            app.L2.Value = string(L) + ' mH/km';
            app.C2.Value = string(C) + ' μF/km';
        end

        % Button pushed function: ex2_clearButton
        function ex2_clearButtonPushed(app, event)
            app.d2.Value = '';
            app.gmr2.Value = '';
            app.bs2.Value = '';
            app.d122.Value = '';
            app.d232.Value = '';
            app.d132.Value = '';
            app.L2.Value = '';
            app.C2.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex1Tab = uitab(app.TabGroup);
            app.Ex1Tab.Title = 'Ex-1';
            app.Example1 = uilabel(app.Ex1Tab);
            app.Example1.Text = {'Example 1: A 500-kV three-phase transposed line is composed of one ACSR 1,272,000-cmil, '; '45/7 Bittern conductor per phase with horizontal conductor'; 'configuration as shown in figure. The conductors have a diameter and a GMR.'; 'Calculation the inductance and capacitance per phase per kilometer of the line. '; '(Hint, all values must be in inch.)'};
            app.Image1 = uiimage(app.Ex1Tab);
            app.Image1.ImageSource = 'ch5-ex1.png';
            app.DiameteroftheconductorEditFieldLabel = uilabel(app.Ex1Tab);
            app.DiameteroftheconductorEditFieldLabel.Text = 'Diameter of the conductor: ';
            app.d1 = uieditfield(app.Ex1Tab, 'text');
            app.d1.Placeholder = '0';
            app.GeometricMeanRadiusGMREditFieldLabel = uilabel(app.Ex1Tab);
            app.GeometricMeanRadiusGMREditFieldLabel.Text = 'Geometric Mean Radius (GMR): ';
            app.gmr1 = uieditfield(app.Ex1Tab, 'text');
            app.gmr1.Placeholder = '0';
            app.d121 = uieditfield(app.Ex1Tab, 'text');
            app.d121.Placeholder = '0';
            app.d231 = uieditfield(app.Ex1Tab, 'text');
            app.d231.Placeholder = '0';
            app.d131 = uieditfield(app.Ex1Tab, 'text');
            app.d131.Placeholder = '0';
            app.ex1_calculateButton = uibutton(app.Ex1Tab, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.Ex1Tab, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.TheinductanceperphaseLabel = uilabel(app.Ex1Tab);
            app.TheinductanceperphaseLabel.Text = 'The inductance per phase: ';
            app.L1 = uieditfield(app.Ex1Tab, 'text');
            app.L1.Editable = 'off';
            app.L1.Placeholder = 'L';
            app.ThecapacitanceperphaseLabel = uilabel(app.Ex1Tab);
            app.ThecapacitanceperphaseLabel.Text = 'The capacitance per phase: ';
            app.C1 = uieditfield(app.Ex1Tab, 'text');
            app.C1.Editable = 'off';
            app.C1.Placeholder = 'C';
            app.Ex2Tab = uitab(app.TabGroup);
            app.Ex2Tab.Title = 'Ex-2';
            app.Example2 = uilabel(app.Ex2Tab);
            app.Example2.Text = {'Example 2: The line in example 1 is replaced by two ACSR 636,000-cmil, 24/7 Rook conductors '; 'which have the same total cross-sectional area of aluminum as one Bittern conductor. '; 'The line spacing as measured from the center of the bundle is the same as before and is shown in figure. '; 'The conductors have a diameter, a GMR and bundle spacing. '; 'Calculation the inductance and capacitance per phase per kilometer of the line and compareit with that '; 'of example 1.  (Hint, all values must be in inch.)'};
            app.Image2 = uiimage(app.Ex2Tab);
            app.Image2.ImageSource = 'ch5-ex2.png';
            app.DiameteroftheconductorEditFieldLabel_2 = uilabel(app.Ex2Tab);
            app.DiameteroftheconductorEditFieldLabel_2.Text = 'Diameter of the conductor: ';
            app.d2 = uieditfield(app.Ex2Tab, 'text');
            app.d2.Placeholder = '0';
            app.GeometricMeanRadiusGMREditFieldLabel_2 = uilabel(app.Ex2Tab);
            app.GeometricMeanRadiusGMREditFieldLabel_2.Text = 'Geometric Mean Radius (GMR): ';
            app.gmr2 = uieditfield(app.Ex2Tab, 'text');
            app.gmr2.Placeholder = '0';
            app.BundlespacingXLabel = uilabel(app.Ex2Tab);
            app.BundlespacingXLabel.Text = 'Bundle spacing (X): ';
            app.bs2 = uieditfield(app.Ex2Tab, 'text');
            app.bs2.Placeholder = '0';
            app.d122 = uieditfield(app.Ex2Tab, 'text');
            app.d122.Placeholder = '0';
            app.d232 = uieditfield(app.Ex2Tab, 'text');
            app.d232.Placeholder = '0';
            app.d132 = uieditfield(app.Ex2Tab, 'text');
            app.d132.Placeholder = '0';
            app.ex2_calculateButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex2_clearButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_clearButtonPushed, true);
            app.ex2_clearButton.Text = 'CLEAR';
            app.TheinductanceperphaseLabel_2 = uilabel(app.Ex2Tab);
            app.TheinductanceperphaseLabel_2.Text = 'The inductance per phase: ';
            app.L2 = uieditfield(app.Ex2Tab, 'text');
            app.L2.Editable = 'off';
            app.L2.Placeholder = 'L';
            app.ThecapacitanceperphaseLabel_2 = uilabel(app.Ex2Tab);
            app.ThecapacitanceperphaseLabel_2.Text = 'The capacitance per phase: ';
            app.C2 = uieditfield(app.Ex2Tab, 'text');
            app.C2.Editable = 'off';
            app.C2.Placeholder = 'C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end