classdef Chapter_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Ex1Tab                          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        DONTDELETELabel_2               matlab.ui.control.Label
        DONTDELETELabel                 matlab.ui.control.Label
        n1                              matlab.ui.control.EditField
        ThetransmissionlineefficiencyEditFieldLabel  matlab.ui.control.Label
        ss1                             matlab.ui.control.EditField
        ThesendingendpowerEditFieldLabel  matlab.ui.control.Label
        vr1                             matlab.ui.control.EditField
        ThevoltageregulationEditFieldLabel  matlab.ui.control.Label
        vs1                             matlab.ui.control.EditField
        ThesendingendvoltageEditFieldLabel  matlab.ui.control.Label
        z1                              matlab.ui.control.EditField
        TheseriesimpedanceperphaseEditFieldLabel  matlab.ui.control.Label
        pf1                             matlab.ui.control.EditField
        ThepowerfactorEditFieldLabel    matlab.ui.control.Label
        d1                              matlab.ui.control.EditField
        ThetransmissionlinelonglLabel   matlab.ui.control.Label
        ind1                            matlab.ui.control.EditField
        TheinductanceperphaseLLabel     matlab.ui.control.Label
        r1                              matlab.ui.control.EditField
        TheresistanceperphaseperkmREditFieldLabel  matlab.ui.control.Label
        v1                              matlab.ui.control.EditField
        ThelinevoltageVEditFieldLabel   matlab.ui.control.Label
        TheapparentpowerSEditFieldLabel  matlab.ui.control.Label
        s1                              matlab.ui.control.EditField
        ex1_clearButton                 matlab.ui.control.Button
        ex1_calculateButton             matlab.ui.control.Button
        LaggingLeading                  matlab.ui.container.ButtonGroup
        Lead1                           matlab.ui.control.RadioButton
        Lag1                            matlab.ui.control.RadioButton
        example1                        matlab.ui.control.Label
        Ex2Tab                          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LaggingLeading2                 matlab.ui.container.ButtonGroup
        Lead2                           matlab.ui.control.RadioButton
        Lag2                            matlab.ui.control.RadioButton
        ex2_clearButton                 matlab.ui.control.Button
        ex2_calculateButton             matlab.ui.control.Button
        d2                              matlab.ui.control.EditField
        TheshuntadmittanceLabel_6       matlab.ui.control.Label
        c2                              matlab.ui.control.EditField
        TheshuntadmittanceLabel_5       matlab.ui.control.Label
        b2                              matlab.ui.control.EditField
        TheshuntadmittanceLabel_4       matlab.ui.control.Label
        a2                              matlab.ui.control.EditField
        TheshuntadmittanceLabel_3       matlab.ui.control.Label
        which2                          matlab.ui.control.EditField
        TheshuntadmittanceLabel_2       matlab.ui.control.Label
        n2                              matlab.ui.control.EditField
        ThetransmissionlineefficiencyEditFieldLabel_2  matlab.ui.control.Label
        vs2                             matlab.ui.control.EditField
        ThesendingendvoltageLabel       matlab.ui.control.Label
        vr2                             matlab.ui.control.EditField
        ThevoltageregulationEditFieldLabel_2  matlab.ui.control.Label
        shunt2                          matlab.ui.control.EditField
        TheshuntadmittanceLabel         matlab.ui.control.Label
        z2                              matlab.ui.control.EditField
        TheseriesimpedanceLabel         matlab.ui.control.Label
        ThetransmissionlinelonglLabel_3  matlab.ui.control.Label
        pf2                             matlab.ui.control.EditField
        leng2                           matlab.ui.control.EditField
        ThepowerfactorEditFieldLabel_2  matlab.ui.control.Label
        y2                              matlab.ui.control.EditField
        ThetransmissionlinelonglLabel_2  matlab.ui.control.Label
        TheapparentpowerSEditFieldLabel_2  matlab.ui.control.Label
        x2                              matlab.ui.control.EditField
        TheinductanceperphaseLLabel_2   matlab.ui.control.Label
        r2                              matlab.ui.control.EditField
        TheresistanceperphaseperkmREditFieldLabel_2  matlab.ui.control.Label
        v2                              matlab.ui.control.EditField
        ThelinevoltageVEditFieldLabel_2  matlab.ui.control.Label
        s2                              matlab.ui.control.EditField
        example2                        matlab.ui.control.Label
    end

    
    methods (Access = private)
        % function that converts polar to rectangular form
        function rectForm = polarToRect(app, event)
            value = event.Source.Value;
            splitOperator = split(value, ',');
            if length(splitOperator) == 2
                app.DONTDELETELabel.Text = 'rect func';
                a = value;
                a = split(a, ',');
                mag = eval(a{1,1});
                phaseAngle = eval(a{2,1});
                real = mag * cos(deg2rad(phaseAngle));
                real = round(real, 2);
                imag = mag * sin(deg2rad(phaseAngle));
                imag = round(imag, 2);
                rectForm = string(complex(real, imag));
            else
                rectForm = value;
            end
        end
        
        % function that converts rectangular to polar form
        function polarForm = rectToPolar(app, value)
            app.DONTDELETELabel_2.Text = 'polar func';
            a = string(value);
            mag = abs(eval(a));
            ang = rad2deg(angle(eval(a)));
            polarForm = '(' + string(round(mag, 2)) + ',' + string(round(ang, 2)) + ')'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: r1, r2, s1, s1, s2, v1, v2, x2
        function polarForm(app, event)
            event.Source.Value = polarToRect(app, event);            
        end

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            % input values
            S = eval(app.s1.Value); % apparent power
            V = eval(app.v1.Value); % line voltage
            R = eval(app.r1.Value); % resistance
            ind = eval(app.ind1.Value); % inductance
            leng = eval(app.d1.Value); % line length
            powfac = eval(app.pf1.Value); % power factor
            f = 60; % frequency

            % ----- CALCULATIONS -----
            
            % lagging or leading
            if app.Lag1.Value == 1
                pf = rad2deg(acos(powfac));
            elseif app.Lead1.Value == 1
                pf = 360 - rad2deg(acos(powfac));
            end
            
            w = 2 * pi * f;
            Z = leng * complex(R, w * ind * 10^(-3)); % series impedance per phase
            
            Vr = V / sqrt(3); % receiving end voltage
            Scom_r = S * complex(cos(deg2rad(pf)), sin(deg2rad(pf))); % complex power at receiving end
            Ir = conj(Scom_r) / (3 * conj(Vr)); % the current per phase
            Vs = Vr + (Z * Ir); % the sending end voltage
            Vs_line = sqrt(3) * abs(Vs); % the sending end line to line voltage
            
            Is = Ir;
            Scom_s = 3 * Vs * conj(Is); % the sending end complex power

            Vreg = ((abs(Vs_line) - abs(V)) / abs(V)) * 100; % voltage regulation
            n = (real(Scom_r) / real(Scom_s)) * 100; % the efficiency
            
            Z = round(Z, 2);
            Vs = round(Vs, 2);
            Scom_s = round(Scom_s, 2);

            % print operations
            app.z1.Value = string(Z) + ' or ' + rectToPolar(app, Z) + ' Ω';
            app.vs1.Value = string(Vs) + ' or ' + rectToPolar(app, Vs) + ' kV';
            app.ss1.Value = string(Scom_s) + ' or ' + rectToPolar(app, Scom_s) + ' MVA';
            app.vr1.Value = string(Vreg) + '%';
            app.n1.Value = string(n) + '%';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.s1.Value = '';
            app.v1.Value = '';
            app.r1.Value = '';
            app.ind1.Value = '';
            app.d1.Value = '';
            app.pf1.Value = '';
            app.z1.Value = '';
            app.vs1.Value = '';
            app.ss1.Value = '';
            app.vr1.Value = '';
            app.n1.Value = '';
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % input values
            S = eval(app.s2.Value); % apparent power
            V = eval(app.v2.Value); % line voltage
            R = eval(app.r2.Value); % resistance
            x = eval(app.x2.Value); % x
            y = eval(app.y2.Value); % y
            leng = eval(app.leng2.Value); % line length
            powfac = eval(app.pf2.Value); % power factor
            % f = 60; % frequency

            % lagging or leading
            if app.Lag2.Value == 1
                pf = rad2deg(acos(powfac));
            elseif app.Lead2.Value == 1
                pf = 360 - rad2deg(acos(powfac));
            end
            
            Z = leng * complex(R, x); % series impedance per phase
            Y = complex(0, y * leng) * 10^(-6); % shunt admittance

            % ABCD constant
            A = 1 + ((Z * Y) / 2);
            B = Z;
            C = Y * ((1 + (Z * Y) / 4));
            D = 1 + ((Z * Y) / 2);
            
            Scom_r = S * complex(cos(deg2rad(pf)), sin(deg2rad(pf))); % complex power at receiving end
            Vr = V / sqrt(3); % receiving end voltage
            Ir = conj(Scom_r) / (3 * conj(Vr)); % the current per phase
           
            % with matrix
            values = [D -B; -C A]^(-1) * [Vr; Ir];
            Vs = values(1,1); % sending end voltage
            Is = values(2,1); % sending end current
            
            Scom_s = 3 * Vs * conj(Is); % the sending end complex power

            Vreg = ((abs(Vs) - abs(Vr)) / abs(Vr)) * 100; % voltage regulation
            n = (real(Scom_r) / real(Scom_s)) * 100; % the efficiency
       
            % print operations
            app.z2.Value = string(Z) + ' or ' + rectToPolar(app, Z) + ' Ω';
            app.shunt2.Value = string(Y) + ' S';
            app.vs2.Value = string(Vs) + ' or ' + rectToPolar(app, Vs) + ' kV';
            app.vr2.Value = string(Vreg) + '%';
            app.n2.Value = string(n) + '%';
            if leng < 80
                app.which2.Value = 'SHORT';
            elseif leng > 80 && leng < 250
                app.which2.Value = 'MEDIUM';
            else
                app.which2.Value = 'LONG';
            end
            app.a2.Value = string(A);
            app.b2.Value = string(B);
            app.c2.Value = string(C);
            app.d2.Value = string(D);
        end

        % Button pushed function: ex2_clearButton
        function ex2_clearButtonPushed(app, event)
            app.s2.Value = '';
            app.v2.Value = '';
            app.r2.Value = '';
            app.x2.Value = '';
            app.y2.Value = '';
            app.leng2.Value = '';
            app.pf2.Value = '';
            app.z2.Value = '';
            app.shunt2.Value = '';
            app.vs2.Value = '';
            app.vr2.Value = '';
            app.n2.Value = '';
            app.which2.Value = '';
            app.a2.Value = '';
            app.b2.Value = '';
            app.c2.Value = '';
            app.d2.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex1Tab = uitab(app.TabGroup);
            app.Ex1Tab.Title = 'Ex-1';
            app.GridLayout = uigridlayout(app.Ex1Tab);
            app.example1 = uilabel(app.GridLayout);
            app.example1.Text = {'Example 1: A Voltage (kV) , three-phase transmission line is d (km) long. The resistance per'; 'phase is R (Ω) per km and the inductance per phase is L (mH) per km. The shunt capacitance '; 'is negligible. Using the short line model to find the voltage and power at the sending end and '; 'the voltage regulation and efficiency when the line is supplying a three-phase load of.'; '* Sample values; polar form: 122,-50 --> mag, anlge: 122,-50  rectangular form: 5+2i, -4i, 12 and etc.'};
            app.LaggingLeading = uibuttongroup(app.GridLayout);
            app.LaggingLeading.Title = 'Lagging & Leading';
            app.Lag1 = uiradiobutton(app.LaggingLeading);
            app.Lag1.Text = 'Lagging';
            app.Lag1.Value = true;
            app.Lead1 = uiradiobutton(app.LaggingLeading);
            app.Lead1.Text = 'Leading';
            app.ex1_calculateButton = uibutton(app.GridLayout, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.GridLayout, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.s1 = uieditfield(app.GridLayout, 'text');
            app.s1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.s1.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.s1.Placeholder = 'MVA';
            app.TheapparentpowerSEditFieldLabel = uilabel(app.GridLayout);
            app.TheapparentpowerSEditFieldLabel.Text = 'The apparent power (S): ';
            app.ThelinevoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.ThelinevoltageVEditFieldLabel.Text = 'The line voltage (V): ';
            app.v1 = uieditfield(app.GridLayout, 'text');
            app.v1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v1.Placeholder = 'kV';
            app.TheresistanceperphaseperkmREditFieldLabel = uilabel(app.GridLayout);
            app.TheresistanceperphaseperkmREditFieldLabel.Text = 'The resistance per phase per km (R): ';
            app.r1 = uieditfield(app.GridLayout, 'text');
            app.r1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r1.Placeholder = 'Ω';
            app.TheinductanceperphaseLLabel = uilabel(app.GridLayout);
            app.TheinductanceperphaseLLabel.Text = 'The inductance per phase per km (L): ';
            app.ind1 = uieditfield(app.GridLayout, 'text');
            app.ind1.Placeholder = 'mH';
            app.ThetransmissionlinelonglLabel = uilabel(app.GridLayout);
            app.ThetransmissionlinelonglLabel.Text = 'The transmission line long (d): ';
            app.d1 = uieditfield(app.GridLayout, 'text');
            app.d1.Placeholder = '< 80 km';
            app.ThepowerfactorEditFieldLabel = uilabel(app.GridLayout);
            app.ThepowerfactorEditFieldLabel.Text = 'The power factor: ';
            app.pf1 = uieditfield(app.GridLayout, 'text');
            app.pf1.Placeholder = 'pf';
            app.TheseriesimpedanceperphaseEditFieldLabel = uilabel(app.GridLayout);
            app.TheseriesimpedanceperphaseEditFieldLabel.Text = 'The series impedance per phase: ';
            app.z1 = uieditfield(app.GridLayout, 'text');
            app.z1.Editable = 'off';
            app.z1.Placeholder = 'Z';
            app.ThesendingendvoltageEditFieldLabel = uilabel(app.GridLayout);
            app.ThesendingendvoltageEditFieldLabel.Text = 'The sending end voltage: ';
            app.vs1 = uieditfield(app.GridLayout, 'text');
            app.vs1.Editable = 'off';
            app.vs1.Placeholder = 'Vs';
            app.ThevoltageregulationEditFieldLabel = uilabel(app.GridLayout);
            app.ThevoltageregulationEditFieldLabel.Text = 'The voltage regulation: ';
            app.vr1 = uieditfield(app.GridLayout, 'text');
            app.vr1.Editable = 'off';
            app.vr1.Placeholder = 'Vr';
            app.ThesendingendpowerEditFieldLabel = uilabel(app.GridLayout);
            app.ThesendingendpowerEditFieldLabel.Text = 'The sending end power: ';
            app.ss1 = uieditfield(app.GridLayout, 'text');
            app.ss1.Editable = 'off';
            app.ss1.Placeholder = 'Ss';
            app.ThetransmissionlineefficiencyEditFieldLabel = uilabel(app.GridLayout);
            app.ThetransmissionlineefficiencyEditFieldLabel.Text = 'The transmission line efficiency: ';
            app.n1 = uieditfield(app.GridLayout, 'text');
            app.n1.Editable = 'off';
            app.n1.Placeholder = 'n';
            app.DONTDELETELabel = uilabel(app.GridLayout);
            app.DONTDELETELabel.Enable = 'off';
            app.DONTDELETELabel.Visible = 'off';
            app.DONTDELETELabel.Text = 'DONT DELETE!';
            app.DONTDELETELabel_2 = uilabel(app.GridLayout);
            app.DONTDELETELabel_2.Enable = 'off';
            app.DONTDELETELabel_2.Visible = 'off';
            app.DONTDELETELabel_2.Text = 'DONT DELETE!';
            app.Ex2Tab = uitab(app.TabGroup);
            app.Ex2Tab.Title = 'Ex-2';
            app.GridLayout2 = uigridlayout(app.Ex2Tab);
            app.example2 = uilabel(app.GridLayout2);
            app.example2.Text = {'Example 2: A three-phase power transmission line has the following characteristics;'; 'length (km), r (Ω/km), x (Ω/km) and y (S/km)'; '* Sample values; polar form: 122,-50 --> mag, anlge: 122,-50  rectangular form: 5+2i, -4i, 12 and etc.'};
            app.s2 = uieditfield(app.GridLayout2, 'text');
            app.s2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.s2.Placeholder = 'MVA';
            app.ThelinevoltageVEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.ThelinevoltageVEditFieldLabel_2.Text = 'The line voltage (V): ';
            app.v2 = uieditfield(app.GridLayout2, 'text');
            app.v2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v2.Placeholder = 'kV';
            app.TheresistanceperphaseperkmREditFieldLabel_2 = uilabel(app.GridLayout2);
            app.TheresistanceperphaseperkmREditFieldLabel_2.Text = 'The resistance per phase per km (R): ';
            app.r2 = uieditfield(app.GridLayout2, 'text');
            app.r2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r2.Placeholder = 'Ω';
            app.TheinductanceperphaseLLabel_2 = uilabel(app.GridLayout2);
            app.TheinductanceperphaseLLabel_2.Text = 'The x per phase per km (x): ';
            app.x2 = uieditfield(app.GridLayout2, 'text');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.x2.Placeholder = 'Ω';
            app.TheapparentpowerSEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.TheapparentpowerSEditFieldLabel_2.Text = 'The apparent power (S): ';
            app.ThetransmissionlinelonglLabel_2 = uilabel(app.GridLayout2);
            app.ThetransmissionlinelonglLabel_2.Text = 'The y per phase per km (y): ';
            app.y2 = uieditfield(app.GridLayout2, 'text');
            app.y2.Placeholder = 'μS';
            app.ThepowerfactorEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.ThepowerfactorEditFieldLabel_2.Text = 'The power factor: ';
            app.leng2 = uieditfield(app.GridLayout2, 'text');
            app.leng2.Placeholder = 'km';
            app.pf2 = uieditfield(app.GridLayout2, 'text');
            app.pf2.Placeholder = 'pf';
            app.ThetransmissionlinelonglLabel_3 = uilabel(app.GridLayout2);
            app.ThetransmissionlinelonglLabel_3.Text = 'The transmission line length (d): ';
            app.TheseriesimpedanceLabel = uilabel(app.GridLayout2);
            app.TheseriesimpedanceLabel.Text = 'The series impedance: ';
            app.z2 = uieditfield(app.GridLayout2, 'text');
            app.z2.Editable = 'off';
            app.z2.Placeholder = 'Z';
            app.TheshuntadmittanceLabel = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel.Text = 'The shunt admittance: ';
            app.shunt2 = uieditfield(app.GridLayout2, 'text');
            app.shunt2.Editable = 'off';
            app.shunt2.Placeholder = 'Y';
            app.ThevoltageregulationEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.ThevoltageregulationEditFieldLabel_2.Text = 'The voltage regulation: ';
            app.vr2 = uieditfield(app.GridLayout2, 'text');
            app.vr2.Editable = 'off';
            app.vr2.Placeholder = 'Vr';
            app.ThesendingendvoltageLabel = uilabel(app.GridLayout2);
            app.ThesendingendvoltageLabel.Text = 'The sending end voltage: ';
            app.vs2 = uieditfield(app.GridLayout2, 'text');
            app.vs2.Editable = 'off';
            app.vs2.Placeholder = 'Vs';
            app.ThetransmissionlineefficiencyEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.ThetransmissionlineefficiencyEditFieldLabel_2.Text = 'The transmission line efficiency: ';
            app.n2 = uieditfield(app.GridLayout2, 'text');
            app.n2.Editable = 'off';
            app.n2.Placeholder = 'n';
            app.TheshuntadmittanceLabel_2 = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel_2.Text = 'Which line?:';
            app.which2 = uieditfield(app.GridLayout2, 'text');
            app.which2.Editable = 'off';
            app.which2.Placeholder = '??';
            app.TheshuntadmittanceLabel_3 = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel_3.Text = 'A:';
            app.a2 = uieditfield(app.GridLayout2, 'text');
            app.a2.Editable = 'off';
            app.a2.Placeholder = 'A';
            app.TheshuntadmittanceLabel_4 = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel_4.Text = 'B:';
            app.b2 = uieditfield(app.GridLayout2, 'text');
            app.b2.Editable = 'off';
            app.b2.Placeholder = 'B';
            app.TheshuntadmittanceLabel_5 = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel_5.Text = 'C:';
            app.c2 = uieditfield(app.GridLayout2, 'text');
            app.c2.Editable = 'off';
            app.c2.Placeholder = 'C';
            app.TheshuntadmittanceLabel_6 = uilabel(app.GridLayout2);
            app.TheshuntadmittanceLabel_6.Text = 'D:';
            app.d2 = uieditfield(app.GridLayout2, 'text');
            app.d2.Editable = 'off';
            app.d2.Placeholder = 'D';
            app.ex2_calculateButton = uibutton(app.GridLayout2, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex2_clearButton = uibutton(app.GridLayout2, 'push');
            app.ex2_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_clearButtonPushed, true);
            app.ex2_clearButton.Text = 'CLEAR';
            app.LaggingLeading2 = uibuttongroup(app.GridLayout2);
            app.LaggingLeading2.Title = 'Lagging & Leading';
            app.Lag2 = uiradiobutton(app.LaggingLeading2);
            app.Lag2.Text = 'Lagging';
            app.Lag2.Value = true;
            app.Lead2 = uiradiobutton(app.LaggingLeading2);
            app.Lead2.Text = 'Leading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end